classdef SlugHeat22_working_220926 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure_Main                     matlab.ui.Figure
        grid_Main                       matlab.ui.container.GridLayout
        button_OpenControlsPanel        matlab.ui.control.Button
        panel_TabGroup                  matlab.ui.container.Panel
        grid_TabGroup                   matlab.ui.container.GridLayout
        Panel_4                         matlab.ui.container.Panel
        panel_TopLabels                 matlab.ui.container.Panel
        grid_TopLabels                  matlab.ui.container.GridLayout
        label_trial                     matlab.ui.control.Label
        label_TrialTitle                matlab.ui.control.Label
        label_Iteration                 matlab.ui.control.Label
        label_IterationTitle            matlab.ui.control.Label
        label_currentpathfull           matlab.ui.control.Label
        label_CurrentPath               matlab.ui.control.Label
        label_SlugHeat                  matlab.ui.control.Label
        panel_ProcessingInfoLabels      matlab.ui.container.Panel
        grid_ProcessingInfoLabels       matlab.ui.container.GridLayout
        label_ProcessingTime            matlab.ui.control.Label
        label_Longitude                 matlab.ui.control.Label
        label_Latitude                  matlab.ui.control.Label
        label_Penetration               matlab.ui.control.Label
        label_Station                   matlab.ui.control.Label
        label_Cruise                    matlab.ui.control.Label
        edit_CruiseTitle                matlab.ui.control.Label
        edit_ProcessingTimeTitle        matlab.ui.control.Label
        edit_LongitudeTitle             matlab.ui.control.Label
        edit_LatitudeTitle              matlab.ui.control.Label
        edit_PenetrationTitle           matlab.ui.control.Label
        edit_StationTitle               matlab.ui.control.Label
        tabgroup_Main                   matlab.ui.container.TabGroup
        tab_PenetrationData             matlab.ui.container.Tab
        grid_PenetrationData            matlab.ui.container.GridLayout
        RemoveSensorsButton             matlab.ui.control.Button
        panel_PD_Thermistors            matlab.ui.container.Panel
        panel_PenDataAxes               matlab.ui.container.Panel
        grid_PenDataAxes                matlab.ui.container.GridLayout
        label_RecordNumber              matlab.ui.control.Label
        axes_Depth                      matlab.ui.control.UIAxes
        axes_BottomWaterTemp            matlab.ui.control.UIAxes
        axes_Tilt                       matlab.ui.control.UIAxes
        axes_TempAboveBWT               matlab.ui.control.UIAxes
        tab_FricitonalDecayReduction    matlab.ui.container.Tab
        grid_FrictionalDecayReduction   matlab.ui.container.GridLayout
        panel_FD_Thermistors            matlab.ui.container.Panel
        panel_FricDecayAxes             matlab.ui.container.Panel
        grid_FricDecayAxes              matlab.ui.container.GridLayout
        axes_TimeShift                  matlab.ui.control.UIAxes
        axes_TempvTau                   matlab.ui.control.UIAxes
        axes_TempvBullFunc              matlab.ui.control.UIAxes
        axes_TempvTime                  matlab.ui.control.UIAxes
        tab_HeatPulseDecayReduction     matlab.ui.container.Tab
        grid_HeatPulseDecayReduction    matlab.ui.container.GridLayout
        panel_HPD_Thermistors           matlab.ui.container.Panel
        panel_HPDecayAxes               matlab.ui.container.Panel
        grid_HPDecayAxes                matlab.ui.container.GridLayout
        axes_TempvTimeShift             matlab.ui.control.UIAxes
        axes_MisfitvTimeShift           matlab.ui.control.UIAxes
        axes_CTempv1_CTime              matlab.ui.control.UIAxes
        axes_CTempvCtime                matlab.ui.control.UIAxes
        tab_HeatFlowAnalysis            matlab.ui.container.Tab
        grid_HeatFlowAnalysis           matlab.ui.container.GridLayout
        panel_BA_Thermistors            matlab.ui.container.Panel
        panel_BullAnalysisAxes          matlab.ui.container.Panel
        grid_BullAnalysisAxes           matlab.ui.container.GridLayout
        panel_CTRvTemp                  matlab.ui.container.Panel
        grid_CTRvTemp                   matlab.ui.container.GridLayout
        panel_HF_Shift                  matlab.ui.container.Panel
        grid_HFShfit                    matlab.ui.container.GridLayout
        label_heatflow                  matlab.ui.control.Label
        label_shift                     matlab.ui.control.Label
        label_ShiftTitle                matlab.ui.control.Label
        label_HeatFlowTitle             matlab.ui.control.Label
        axes_TempvCTR                   matlab.ui.control.UIAxes
        panel_TCvDepth                  matlab.ui.container.Panel
        grid_TCvDepth                   matlab.ui.container.GridLayout
        panel_averagek                  matlab.ui.container.Panel
        grid_Averagek                   matlab.ui.container.GridLayout
        label_averagek                  matlab.ui.control.Label
        label_AveragekTitle             matlab.ui.control.Label
        axes_TCvDepth                   matlab.ui.control.UIAxes
        panel_TempvDepth                matlab.ui.container.Panel
        grid_TempvDepth                 matlab.ui.container.GridLayout
        grid_GradientDepth              matlab.ui.container.GridLayout
        label_GradientTitle             matlab.ui.control.Label
        label_depthoftop                matlab.ui.control.Label
        label_DepthofTopTitle           matlab.ui.control.Label
        label_gradient                  matlab.ui.control.Label
        axes_TempvDepth                 matlab.ui.control.UIAxes
        tab_HeatFlowRegressionAnalysis  matlab.ui.container.Tab
        grid_HeatFlowRegressionAnalysis  matlab.ui.container.GridLayout
        panel_HF_Thermistors            matlab.ui.container.Panel
        panel_HeatFlowRegAxes           matlab.ui.container.Panel
        grid_HeatFlowRegAxes            matlab.ui.container.GridLayout
        axes_HeatFlow                   matlab.ui.control.UIAxes
        axes_Sigma                      matlab.ui.control.UIAxes
        tab_SensitivityAnalysis         matlab.ui.container.Tab
        grid_SensitivityAnalysis        matlab.ui.container.GridLayout
        panel_SA_Thermistors            matlab.ui.container.Panel
        panel_SensAnalysisAxes          matlab.ui.container.Panel
        grid_SensAnalysisAxes           matlab.ui.container.GridLayout
        panel_SA_TCvDepth               matlab.ui.container.Panel
        grid_SA_TCvDepth                matlab.ui.container.GridLayout
        Panel_6                         matlab.ui.container.Panel
        grid_iterations                 matlab.ui.container.GridLayout
        label_standev                   matlab.ui.control.Label
        label_StanDevTitle              matlab.ui.control.Label
        label_currentiteration          matlab.ui.control.Label
        label_CurrentIterationTitle     matlab.ui.control.Label
        axes_SA_SDvIter                 matlab.ui.control.UIAxes
        panel_SA_SDvIter                matlab.ui.container.Panel
        grid_SA_SDvIter                 matlab.ui.container.GridLayout
        panel_heatflow_SA               matlab.ui.container.Panel
        grid_heatflow_SA                matlab.ui.container.GridLayout
        label_currentheatflow           matlab.ui.control.Label
        label_CurrentHFTitle            matlab.ui.control.Label
        label_initheatflow              matlab.ui.control.Label
        label_InitHeatFlowTitle         matlab.ui.control.Label
        label_heatflowrange             matlab.ui.control.Label
        label_HFRangeTitle              matlab.ui.control.Label
        label_meanheatflow              matlab.ui.control.Label
        label_MeanHFTitle               matlab.ui.control.Label
        axes_SA_TempvCTR                matlab.ui.control.UIAxes
        panel_SA_TempvCTR               matlab.ui.container.Panel
        grid_SA_TempvCTR                matlab.ui.container.GridLayout
        grid_distributions              matlab.ui.container.GridLayout
        label_kDistribution             matlab.ui.control.Label
        label_thermconbounds            matlab.ui.control.Label
        label_ThermConBoundsTitle       matlab.ui.control.Label
        label_meandev                   matlab.ui.control.Label
        label_minthickness              matlab.ui.control.Label
        label_MeanDevTitle              matlab.ui.control.Label
        label_MinThickTitle             matlab.ui.control.Label
        axes_SA_TCvDepth                matlab.ui.control.UIAxes
        tab_IterativevsInversionMethods  matlab.ui.container.Tab
        panel_ItervInvMethods           matlab.ui.container.Panel
        axes_HeatPulseTesting           matlab.ui.control.UIAxes
        tab_HFSummary                   matlab.ui.container.Tab
        panel_HFSummary                 matlab.ui.container.Panel
        grid_HFSummary                  matlab.ui.container.GridLayout
        button_saveAllResults           matlab.ui.control.Button
        button_clearErrResults          matlab.ui.control.Button
        button_saveErrRestults          matlab.ui.control.Button
        panel_ErrorUncertaintyAnalysisTable  matlab.ui.container.Panel
        table_ErrorUncertaintySummary   matlab.ui.control.Table
        button_saveresults              matlab.ui.control.Button
        button_clearresults             matlab.ui.control.Button
        table_HFSummary                 matlab.ui.control.Table
        Tab                             matlab.ui.container.Tab
        panel_ControlWindow             matlab.ui.container.Panel
        grid_ControlWindow              matlab.ui.container.GridLayout
        panel_PlotControls_Pen          matlab.ui.container.Panel
        grid_PlotControls_Pen           matlab.ui.container.GridLayout
        checkbox_DepthPlot              matlab.ui.control.CheckBox
        checkbox_BottomWaterPlot        matlab.ui.control.CheckBox
        checkbox_TiltPlot               matlab.ui.control.CheckBox
        panel_PlotControls_Fric         matlab.ui.container.Panel
        grid_PlotControls_Fric          matlab.ui.container.GridLayout
        buttongroup_PlotControls_Fric   matlab.ui.container.ButtonGroup
        button_MisfitvsShiftFric        matlab.ui.control.RadioButton
        button_TempvsBullFunFric        matlab.ui.control.RadioButton
        button_TempvsTauFric            matlab.ui.control.RadioButton
        button_TempvsTimeFric           matlab.ui.control.RadioButton
        button_ShowAllFric              matlab.ui.control.RadioButton
        panel_PlotControls_HeatPulse    matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        buttongroup_PlotControls_HeatPulse  matlab.ui.container.ButtonGroup
        button_TempvTimeShiftHP         matlab.ui.control.RadioButton
        button_TempvsInvTimeHP          matlab.ui.control.RadioButton
        button_MisfitvsTimeShiftHP      matlab.ui.control.RadioButton
        button_TempvsTimeHP             matlab.ui.control.RadioButton
        button_ShowAllHP                matlab.ui.control.RadioButton
        panel_PlotControls_HeatFlow     matlab.ui.container.Panel
        grid_PlotControls_HeatFlow      matlab.ui.container.GridLayout
        checkbox_TCvDepth               matlab.ui.control.CheckBox
        checkbox_TempvDepth             matlab.ui.control.CheckBox
        checkbox_TempvCTR               matlab.ui.control.CheckBox
        panel_PlotControls_Scatter      matlab.ui.container.Panel
        grid_Scatter                    matlab.ui.container.GridLayout
        checkbox_SigmaPlot              matlab.ui.control.CheckBox
        checkbox_HFPlot                 matlab.ui.control.CheckBox
        panel_PlotControls_SensAn       matlab.ui.container.Panel
        grid_PlotControls_Pen_2         matlab.ui.container.GridLayout
        panel_Commands                  matlab.ui.container.Panel
        grid_Commands                   matlab.ui.container.GridLayout
        checkbox_UseWS                  matlab.ui.control.CheckBox
        button_Process                  matlab.ui.control.Button
        checkbox_UseHP                  matlab.ui.control.CheckBox
        ResetButton                     matlab.ui.control.Button
        switch_PauseBetweenIter         matlab.ui.control.Switch
        PauseIterationsLabel            matlab.ui.control.Label
        NextIterationButton             matlab.ui.control.Button
        EndButton                       matlab.ui.control.Button
        button_DiscardSensors           matlab.ui.control.Button
        button_SensAn                   matlab.ui.control.Button
        panel_header                    matlab.ui.container.Panel
        grid_header                     matlab.ui.container.GridLayout
        image_SlugHeat                  matlab.ui.control.Image
        panel_Logos                     matlab.ui.container.Panel
        grid_Logos                      matlab.ui.container.GridLayout
        image_OSULogo                   matlab.ui.control.Image
        image_WHLogo                    matlab.ui.control.Image
        image_UCSCLogo                  matlab.ui.control.Image
        button_Restart                  matlab.ui.control.Button
        button_ControlWindowExit        matlab.ui.control.Button
        panel_PenetrationInfo           matlab.ui.container.Panel
        grid_PenetrationInfo            matlab.ui.container.GridLayout
        button_LoadPen                  matlab.ui.control.Button
        label_tapfilename               matlab.ui.control.Label
        label_TapFile                   matlab.ui.control.Label
        label_penfilename               matlab.ui.control.Label
        label_PenFile                   matlab.ui.control.Label
        panel_Parameters                matlab.ui.container.Panel
        grid_Parameters                 matlab.ui.container.GridLayout
        label_calfilename               matlab.ui.control.Label
        label_CalFile                   matlab.ui.control.Label
        label_parfilename               matlab.ui.control.Label
        button_SetlParams               matlab.ui.control.Button
        label_ParFile                   matlab.ui.control.Label
        panel_OutputFiles               matlab.ui.container.Panel
        grid_OutputFiles                matlab.ui.container.GridLayout
        label_resfilename               matlab.ui.control.Label
        label_ResFile                   matlab.ui.control.Label
        label_logfilename               matlab.ui.control.Label
        label_LogFile                   matlab.ui.control.Label
        label_resultsfilename           matlab.ui.control.Label
        panel_DataControls              matlab.ui.container.Panel
        grid_DataControls               matlab.ui.container.GridLayout
        edit_PenEnd                     matlab.ui.control.NumericEditField
        PenetrationEndLabel             matlab.ui.control.Label
        edit_HP                         matlab.ui.control.NumericEditField
        HeatPulseLabel                  matlab.ui.control.Label
        edit_PenStart                   matlab.ui.control.NumericEditField
        PenetrtionStartLabel            matlab.ui.control.Label
    end

    
    properties (Access = public)
        
        % Outputs from Initialize Program function
        % ---------------------------------        
            Version
            Update
			NumberOfColumns
			CurrentPath
            CurrentDateTime
            ParFile
            ParFilePath
            ParFileName
            DefaultParFile
    	    CalFile
            CalFilePath
            CalFileName
            ProgramLogId
        
        % Outputs from GetFiles function
        % ---------------------------------
			PenFileName
            PenFilePath
            PenFile
			TAPName
            TAPFileName
            TAPFile
            MATFileName
            MATFile
			LogFileName
            LogFile
			ResFileName
            ResFile 
            ResFileId
            LogFileId

       % Outputs from ReadParFile function
       % ---------------------------------
            NumberOfSensors	
            WaterThermistor
            TimeScalingFactor
            DeltaTime
            SensorRadius
            SensorDistance
            TempError
            CalibrationCoeffs
            HyndmanCoeffs
            FrictionalDelays
            FricMaxStep
            TimeInc
            FricTauMin
            FricTauMax
            PulseDelays
            kInit
            PulsePower
            TimeShiftInit
            TimeShiftInc
            PulseMaxStep
            kTolerance
            PulseTauMin
            PulseTauMax
            HeatPulseLength
            MinTotalkChange
            MaxNumberOfIterations
            MaxSAIterations
            Sigmak0
            kMin
            kMax
            MinThickness
            UseFrictional
            kAnisotropy
            TopSensorDepth 

        % Outputs from ReadPenFile function
        % ---------------------------------
            S_MATFile
            FullExpeditionName 
            StationName 
            Penetration 
            CruiseName 
            Datum 
            Latitude 
            Longitude 
            DepthMean
            TiltMean
            LoggerId
            ProbeId
            %NumberOfSensors
            PenetrationRecord
            HeatPulseRecord
            EndRecord
            BottomWaterRawData
            AllRecords
            AllSensorsRawData
            WaterSensorRawData
            EqmStartRecord
            EqmEndRecord 

        % Outputs from ReadTAPFile function
        % ---------------------------------
            TAPRecord
            Tilt
            Depth
        
        % Outputs from ReadCalFile function
        % ---------------------------------
            WaterCorrectionType
            Offset

        % Outputs from CorrectTemp function
        % ---------------------------------
            BottomWaterTemp
            WaterSensorTemp
            AllSensorsTemp

        % Outputs from SplitDecays function
        % ---------------------------------
            FricTime
            FricTemp
            PulseData
            PulseTime
            PulseTemp
        
        % Outputs from PlotRawData function
        % ---------------------------------
            Badk
            BadT
            S_Lines
            AllSensors
            h_axTempAboveBWT
        
       % Outputs from PlotCheckboxes function
       % ------------------------------------
            PenCheckboxes
            FricCheckboxes
            HPCheckboxes 

        % Outputs from RemoveSensors function
        % --------------------------------------
            IgnoredSensors
            SensorsToUse

        % Outputs from InitializeProcessing function
        % -------------------------------------------
            kFunction
            Currentk
            CurrentT
            TChange
            kChange
            Iteration
            FirstIteration
            TotalIterations
            AnotherTrial
            RelativeDepths
            A
            B
            C
            D
            E
            F

        % Outputs from FrictionalDecay function
        % --------------------------------------
            NumberOfFricUsedPoints
            MinimumFricEqTemp
            MinimumFricError
            MinimumFricDelays
            MinimumFricSlope
            HPTooLow
            ShiftedTime
            IndexOfMinimums
            DataTemp
            TimeShifts
            ShiftedTau
            DataFAT
            DataLimits
            b
            a
            RMS
        
        % Outputs from FrictionalDecayPlot function
        % -----------------------------------------
            h_axFricTempvTime
            h_axFricTempvTau
            h_axFricTempvTauPoints
            h_axFricTempvTauLines
            h_axFricRMSvTimeShift
            h_axFricRMSvTimeShiftMinDelays

        % Outputs from HeatPulseDecay Function
        % -------------------------------------
            MeankPointAtMinkDiff
            kSlopeAtMinkDiff
            MeankPointAtZeroInfTemp
            MeankPointAtMinRMS
            kSlopeAtZeroInfTemp
            kSlopeAtMinRMS
            TempAtInf
            NumberOfUsedPoints
            MinimumPulseDelays
            kError
            h_axHPTempvTime
            h_axHPRMS
            h_axHPRMSLine
            h_axHPTempvInvTime
            h_axHPTempvInvTimeBestFit
            h_axHPTempvTimeShift
            h_axHPTempvTimeShiftBestFit

        % Outputs from HeatFlowAnalysis Function
        % -------------------------------------
           SensorsUsedForBullardFit
           GoodkIndex
           CTRToUse
           CTR
           ShiftedCTR
           ShiftedRelativeDepths
           SigmaR
           PenetrationLag
           Slope
           Shift
           S_BullPlots
           HeatFlow
           Averagek
           Gradient


        % Outputs from IteratePlots Function
        % -----------------------------------
           TempvTimeChildren
           TempvTauChildren
           TempvBullFuncChildren
           TimeShiftChildren
           CTempvCtimeChildren
           MisfitvTimeShiftChildren
           CTempv1_CTimeChildren
           TempvTimeShiftChildren 

        % Outputs from HeatFlowRegression Function
        % -------------------------------------
           Scatter
           TempOverDepth
           Sigmaa
           Sigmab 
           HFLine
           ScatterLine
           SigmaHFLine
           SigmaScatterLine

        % Outputs from SensitivityAnalysis Function
        % -------------------------------------
        kDistribution
        r


    end
    
    properties (Access = private)
        
        % Variables for plotting
        %   if == 1: plot on
        %   if ~= 1: plot off
        % -------------------------
        TempPlot
        DepthPlot 
        BWPlot
        TiltPlot

        TempTimePlot
        TempTauPlot
        TempBullFuncPlot
        MisfitShiftPlot

        TempTimePlotHPPlot    
        MisfitShiftHPPlot     
        TempInvTimeHPPlot      
        TempTimeShiftHPPlot

        BullTempvDepthPlot
        TCvDepthPlot
        TempvCTRPlot


        SigmaPlot
        HFPlot


        % Auxiallary Dialog Apps
        % -------------------------
        SetParamsDialogApp 
        DiscardDataDialogApp
        Status
        SetUpSensDialogApp
    
        % Value to tell app that record numbers 
        % have been changed manually by user
        % -------------------------        
        PenStartChanged
        HeatPulseChanged
        PenEndChanged 
        
        % Each time a single penetration is processed = 1 trial
        % ------------------------------------------------------
        Trial
        PreviousT
        Previousk

        % Checkbox comma separated lists
        % -------------------------------
        BMCheckboxes
        HFRCheckboxes
        SACheckboxes

        % Checkbox for pausing between iterations
        % -------------------------------------
        Pause 
        Converged


        % Colors
        % ----------
        LightGrey = [0.97 0.97 0.97]
        Primary 
        Secondary
        
        % Save original number of sensors before discarding from dataset
        % ----------------------------------------------------------------
        OrigNumberOfSensors
        kToUse % sensors whose therm cons are usable

        % Properties to tell program whether to run certain analyses
        % --------------------------------------------------------------
        UseWaterSensor %Value for checkbox allowing user to use or ignore BW sensor
        UseHP %Value for checkbox allowing user to use or ignore heat pulse

        NoInitialize % don't initialize if you turn heat pulse off, but there still is actually a heat pulse
        PenNotes % Notes about penetration to be saved in log file, res file, and results summary table
        
        Reprocess % when 1, the entire iteration process needs to be done, regardless of other factors. 
                  % If 0, only the Heat Flow Analysis needs to be redone (calculations of eq. temp and therm. con. do not)
        SensorsRemoved % This is 1 if sensors were removed BEFORE calculations for eq. temp and therm. con were made

        SensAnalysisRunning % When this is on, the Bullard Analysis 
                            % function will compute but not plot. This way 
                            % the sensitivity analysis can use the Bullard 
                            % function without overriding its plot
        
        RunAnalysis % the analysis is only run if this value is 1. The value is only 1 if the button 'Run Sensitivity Analysis' was pressed on aux app

        % For results
        % -----------
        HFData % Heat Flow ResultsSummary data
        ErrData % Error and Uncertatinty results summary data
    end
    
    methods (Access = public)
       

            function setParams(app, watercorrect, offset, numsen, watertherm, timescale, deltatime, sensrad, sensdist, temperror, hyndco, ...
                fricdelay, fricmaxstep, timeinc, frictaumin, frictaumax, pulsedelay, kinit, pulsepow, timshiftinit, timeshiftinc, pulsemaxstep, ktol, ...
                pulsetaumin, pulsetaumax, HPlength, minkchange, maxiter, maxSAiter, sigk, kmin, kmax, minthick, usefric, kAni, topsensor)
            
                app.WaterCorrectionType     = watercorrect;
                app.Offset                  = offset;    
                app.NumberOfSensors         = numsen; 
                app.WaterThermistor         = watertherm;
                app.TimeScalingFactor       = timescale;
                app.DeltaTime               = deltatime;
                app.SensorRadius            = sensrad;
                app.SensorDistance          = sensdist;
                app.TempError               = temperror;
                app.HyndmanCoeffs           = hyndco;
                app.FrictionalDelays        = repmat(fricdelay, 1, app.NumberOfSensors);
                app.FricMaxStep             = fricmaxstep;
                app.TimeInc                 = timeinc;
                app.FricTauMin              = frictaumin;
                app.FricTauMax              = frictaumax;
                app.PulseDelays             = repmat(pulsedelay, 1, app.NumberOfSensors);
                app.kInit                   = kinit;
                app.PulsePower              = pulsepow;
                app.TimeShiftInit           = timshiftinit;
                app.TimeShiftInc            = timeshiftinc;
                app.PulseMaxStep            = pulsemaxstep;
                app.kTolerance              = ktol;
                app.PulseTauMin             = pulsetaumin;
                app.PulseTauMax             = pulsetaumax;
                app.HeatPulseLength         = HPlength;
                app.MinTotalkChange         = minkchange;
                app.MaxNumberOfIterations   = maxiter;
                app.MaxSAIterations         = maxSAiter;
                app.Sigmak0                 = sigk;
                app.kMin                    = kmin;
                app.kMax                    = kmax;
                app.MinThickness            = minthick;
                app.UseFrictional           = usefric;
                app.kAnisotropy             = kAni;
                app.TopSensorDepth          = topsensor;

            end

            function discardData(app, BadT, Badk, Status)
                app.BadT = BadT;
                app.Badk = Badk;
                app.Status = Status;
            end
        
        
            function setSens(app, MaxSAIterations, Sigmak0, kMin, kMax, MinThickness, UseFrictional, kAnisotropy, kDistribution, r, RunAnalysis)
                app.MaxSAIterations = MaxSAIterations;
                app.Sigmak0 = Sigmak0;
                app.kMin = kMin;
                app.kMax = kMax;
                app.MinThickness = MinThickness;
                app.UseFrictional = UseFrictional;
                app.kAnisotropy = kAnisotropy;
                app.kDistribution = kDistribution;
                app.r = r;
                app.RunAnalysis = RunAnalysis;
            end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        % Downsize fonts for command window if using laptop screen
        % -----------------------------------------------------------
        %ScreenSize = get(0,'ScreenSize');
        %if ScreenSize(3) == 1440 && ScreenSize(4) == 900
        %    app.panel_PlotControls_HeatFlow.FontSize = 12;
        %    app.panel_PlotControls_Fric.FontSize = 12;
        %    app.panel_PlotControls_HeatPulse.FontSize = 12;
        %    app.panel_PlotControls_Pen.FontSize = 12;
        %    app.panel_PlotControls_Scatter.FontSize = 12;
%
        %    app.panel_Commands.FontSize = 12;
        %    app.panel_DataControls.FontSize = 12;
        %    app.panel_OutputFiles.FontSize = 12;
        %    app.panel_Parameters.FontSize = 12;
        %    app.panel_PenetrationInfo.FontSize = 12;
%
        %    app.label_ParFile.FontSize = 10;
        %    app.label_CalFile.FontSize = 10;
        %    app.label_PenFile.FontSize = 10;
        %    app.label_TapFile.FontSize = 10;
        %    app.label_LogFile.FontSize = 10;
        %    app.label_ResFile.FontSize= 10;
        %    app.edit_PenStart.FontSize = 10;
        %    app.edit_PenEnd.FontSize = 10;
        %    app.edit_HP.FontSize = 10;
        %    app.label_penfilename.FontSize = 10;
        %    app.label_parfilename.FontSize = 10;
        %    app.label_tapfilename.FontSize = 10;
        %    app.label_logfilename.FontSize = 10;
        %    app.label_resfilename.FontSize = 10;
        %    app.label_calfilename.FontSize = 10;
%
        %    app.button_SetlParams.FontSize = 10;
        %    app.button_Process.FontSize = 12;
        %    app.button_LoadPen.FontSize = 12;
        %    app.button_SensAn.FontSize = 12;
        %    app.button_DiscardSensors.FontSize = 12;
        %    app.button_Restart.FontSize = 12;
        %    app.ResetButton.FontSize = 12;
        %    app.NextIterationButton.FontSize = 12;
        %    app.EndButton.FontSize = 12;
%
        %    app.switch_PauseBetweenIter.FontSize = 12;
%
        %    app.grid_ControlWindow.RowHeight = {'0.3x','0.4x','0.4x','0.25x','0.3x','0.35x','0.8x'};
%
        %    app.label_AveragekTitle.FontSize = 12;
        %    app.label_DepthofTopTitle.FontSize = 12;
        %    app.label_GradientTitle.FontSize = 12;
        %    app.label_HeatFlowTitle.FontSize = 12;
        %    app.label_ShiftTitle.FontSize = 12;
%
        %    app.label_averagek.FontSize = 12;
        %    app.label_gradient.FontSize = 12;
        %    app.label_depthoftop.FontSize = 12;
        %    app.label_heatflow.FontSize = 12;
        %    app.label_shift.FontSize = 12;

            
        app.figure_Main.WindowState = 'maximized';
        figure(app.figure_Main)

        if ~app.WaterThermistor
            app.UseWaterSensor.Enable = 'off';
        end

        % Disable sensitivity analysis functions until one is run
        % -------------------------------------------------------
        app.button_saveAllResults.Enable = 'off';
        app.button_saveErrRestults.Enable = 'off';
        app.button_clearErrResults.Enable = 'off';

        % Plots are all turned ON to start
        % --------------------------------
        app.BWPlot = 1;
        app.DepthPlot = 1;
        app.TiltPlot = 1;
        app.TempPlot = 1;

        app.TempTimePlot = 1;
        app.TempTauPlot = 1;
        app.TempBullFuncPlot = 1;
        app.MisfitShiftPlot = 1;

        app.BullTempvDepthPlot = 1;
        app.TCvDepthPlot = 1;
        app.TempvCTRPlot = 1;

        app.HFPlot = 1;
        app.SigmaPlot = 1;

        app.UseWaterSensor = 1;
        app.UseHP = 1;
        app.NoInitialize = 0;
        app.Reprocess = 0;
        app.SensorsRemoved = 0;

        app.SensAnalysisRunning = 0;
        app.RunAnalysis = 0;
        
        % Record numbers for penetration, heat pulse, and end 
        % are unchanged to start
        % -----------------------------------------------------
        app.PenStartChanged=0;
        app.HeatPulseChanged=0;
        app.PenEndChanged=0;

        % Begin at Trial 0
        % -----------------
        app.Trial = 0;
        app.Status = 0;

        app.Pause = 0;
        app.Converged = 0;

        % Plot controls originally set to penetration data tab 
        % ----------------------------------------------------
        app.panel_PlotControls_Fric.Visible = 'off';
        app.panel_PlotControls_HeatPulse.Visible = 'off';
        app.panel_PlotControls_HeatFlow.Visible = 'off';
        app.panel_PlotControls_Scatter.Visible = 'off';
        app.buttongroup_PlotControls_Fric.Position = [10 40 100 22];
        app.buttongroup_PlotControls_HeatPulse.Position = [10 40 100 22];


%         Initialize program
%         -------------------------------------------------
            [app.Version, app.Update, ...
			app.NumberOfColumns, ...
			app.CurrentPath, app.CurrentDateTime, ...
            app.ParFile, app.ParFilePath, app.ParFileName, ...
            app.DefaultParFile, ...
    	    app.CalFile, app.CalFilePath, app.CalFileName, app.ProgramLogId...
            ] =  InitializeProgram(...
            app.DepthPlot, app.TiltPlot, app.BWPlot, ...
            app.image_UCSCLogo, app.image_WHLogo, app.image_OSULogo, ...
            app.image_SlugHeat, app.button_ControlWindowExit, app.button_OpenControlsPanel);

            % Stop and inform user they are in wrong directory, if they are
            % -------------------------------------------------------------
            while ~exist(fullfile(app.CurrentPath, 'ReadParFile.m'), 'file')
                uialert(app.figure_Main, ['ERROR: You are in the wrong directory. ...' ...
                    'Please be sure your current path holds all of the necessary files'], ...
                    'Wrong Directory', 'Icon','error')
                uiwait(app.figure_Main)
            end
           
            uiresume(app.figure_Main)

%         Read in parameters from parameters file
%         -------------------------------------------------

            [app.NumberOfSensors, ...	
            app.WaterThermistor, ...
            app.TimeScalingFactor, ...
            app.DeltaTime, ...
            app.SensorRadius, ...
            app.SensorDistance, ...
            app.TempError, ...
            app.CalibrationCoeffs, ...
            app.HyndmanCoeffs, ...
            app.FrictionalDelays, ...
            app.FricMaxStep, ...
            app.TimeInc, ...
            app.FricTauMin, ...
            app.FricTauMax, ...
            app.PulseDelays, ...
            app.kInit, ...
            app.PulsePower, ...
            app.TimeShiftInit, ...
            app.TimeShiftInc, ...
            app.PulseMaxStep, ...
            app.kTolerance, ...
            app.PulseTauMin, ...
            app.PulseTauMax, ...
            app.HeatPulseLength, ...
            app.MinTotalkChange, ...
            app.MaxNumberOfIterations, ...
            app.MaxSAIterations, ...
            app.Sigmak0, ...
            app.kMin, ...
            app.kMax, ...
            app.MinThickness, ...
            app.UseFrictional, ...
            app.kAnisotropy, ...
            app.TopSensorDepth ...
            ] = ReadParFile(app.ParFile, app.ProgramLogId);

%         Read in information from calibration file
%         -------------------------------------------------
          [app.WaterCorrectionType, app.Offset ...
           ] = ReadCalFile(app.CalFile, app.ProgramLogId);

%           Update layout
%         -------------------------
            updateLabels(app.label_currentpathfull, app.CurrentPath, app.label_calfilename, ...
            app.CalFileName, app.label_parfilename, app.ParFileName, app.label_penfilename, ...
            app.PenFileName, app.label_tapfilename, app.TAPFileName, app.label_resfilename, ...
            app.ResFileName, app.label_logfilename, app.LogFileName, app.label_Cruise, app.CruiseName, ...
            app.label_Station, app.StationName, app.label_Penetration, app.Penetration, ...
            app.label_Latitude, app.Latitude, app.label_Longitude, app.Longitude, app.edit_PenStart, ...
            app.PenetrationRecord, app.edit_PenEnd, app.EndRecord, app.edit_HP, app.HeatPulseRecord)   

        end

        % Button pushed function: button_ControlWindowExit
        function button_ControlWindowExitButtonPushed(app, event)
            app.grid_Main.ColumnWidth = {'0x', '.01x', '1x'};

            % Align X axes of all visibile plots
            % ------------------------------------
            xAlign(app.DepthPlot, app.axes_Depth, app.BWPlot, app.axes_BottomWaterTemp, ...
                app.TiltPlot, app.axes_Tilt, app.TempPlot, app.axes_TempAboveBWT)

            % Align X axes of the two time shift plots on the HEAT PULSE
            % DECAY tab
            % ----------------------------------------------------------
            xAlignHPD(app.button_ShowAllHP, app.axes_MisfitvTimeShift, app.axes_TempvTimeShift)

        end

        % Button pushed function: button_LoadPen
        function button_LoadPenPushed(app, event)
% =========================================================================
%         Set up for processings and data preparation
% =========================================================================

%         Read in parameters from parameters file
%         -------------------------------------------------

            [app.NumberOfSensors, ...	
            app.WaterThermistor, ...
            app.TimeScalingFactor, ...
            app.DeltaTime, ...
            app.SensorRadius, ...
            app.SensorDistance, ...
            app.TempError, ...
            app.CalibrationCoeffs, ...
            app.HyndmanCoeffs, ...
            app.FrictionalDelays, ...
            app.FricMaxStep, ...
            app.TimeInc, ...
            app.FricTauMin, ...
            app.FricTauMax, ...
            app.PulseDelays, ...
            app.kInit, ...
            app.PulsePower, ...
            app.TimeShiftInit, ...
            app.TimeShiftInc, ...
            app.PulseMaxStep, ...
            app.kTolerance, ...
            app.PulseTauMin, ...
            app.PulseTauMax, ...
            app.HeatPulseLength, ...
            app.MinTotalkChange, ...
            app.MaxNumberOfIterations, ...
            app.MaxSAIterations, ...
            app.Sigmak0, ...
            app.kMin, ...
            app.kMax, ...
            app.MinThickness, ...
            app.UseFrictional, ...
            app.kAnisotropy, ...
            app.TopSensorDepth ...
            ] = ReadParFile(app.ParFile, app.ProgramLogId);


%         Reset all
%         ------------------------------------------
            app.SensorsToUse = 1:app.OrigNumberOfSensors;
            app.Trial = 0;
            app.Reprocess = 0;
            app.SensorsRemoved = 0;
            app.UseWaterSensor = 1;
            app.WaterThermistor = 1;
            app.UseHP = 1;
            app.IgnoredSensors = [];


            ResetAll(app.axes_TempAboveBWT, app.axes_BottomWaterTemp, ...
            app.axes_Depth, app.axes_Tilt, app.axes_TempvTime, app.axes_TempvTau, ...
            app.axes_TempvBullFunc, app.axes_TimeShift, app.axes_CTempvCtime, ...
            app.axes_CTempv1_CTime, app.axes_MisfitvTimeShift, app.axes_TempvTimeShift, ...
            app.axes_TempvDepth, app.axes_TCvDepth, app.axes_TempvCTR, ...
            app.axes_HeatFlow, app.axes_Sigma, ...
            app.label_gradient, app.label_depthoftop, app.label_averagek, app.label_heatflow, app.label_shift, ...
            app.label_trial, app.label_Iteration, app.label_currentpathfull, ...
            app.label_parfilename, app.label_calfilename, app.label_penfilename, app.label_tapfilename, ...
            app.label_logfilename, app.label_resfilename, ...
            app.edit_PenStart, app.edit_HP, app.edit_PenEnd, ...
            app.checkbox_BottomWaterPlot, app.checkbox_DepthPlot, ...
            app.checkbox_TiltPlot, app.PenCheckboxes, app.FricCheckboxes, app.HPCheckboxes, ...
            app.checkbox_UseWS, app.checkbox_UseHP, ...
            app.S_MATFile, app.NumberOfSensors, app.ProgramLogId, app.SensorsToUse)

            % Reset all plots for senstivity analysis
            % -----------------------------------------
            delete(app.axes_SA_TCvDepth.Children)
            delete(app.axes_SA_TempvCTR.Children)
            delete(app.axes_SA_SDvIter.Children)
            

%         Update layout
%         ---------------
            app.label_ProcessingTime.Text = datestr(datetime('now'));
            figure(app.figure_Main)


%         Get the penetration and tap file, create log file and results file
%         -------------------------------------------------------------
			[app.PenFileName, app.PenFilePath, app.PenFile, ...
			app.TAPName, app.TAPFileName, app.TAPFile, ...
            app.MATFileName, app.MATFile, ...
			app.LogFileName, app.LogFile, ...
			app.ResFileName, app.ResFile, ...
            app.ResFileId, app.LogFileId ...
			] = GetFiles(app.Version, app.Update, ...
			app.NumberOfColumns, ...
			app.CurrentPath, app.CurrentDateTime, app.ParFile, app.NumberOfSensors, ...
            app.TimeScalingFactor, ...
            app.SensorRadius, ...
            app.SensorDistance, ...
            app.CalibrationCoeffs, ...
            app.HyndmanCoeffs, ...
            app.FrictionalDelays, ...
            app.FricMaxStep, ...
            app.TimeInc, ...
            app.FricTauMin, ...
            app.FricTauMax, ...
            app.PulseDelays, ...
            app.kInit, ...
            app.PulsePower, ...
            app.TimeShiftInc, ...
            app.PulseMaxStep, ...
            app.kTolerance, ...
            app.PulseTauMin, ...
            app.PulseTauMax, ...
            app.HeatPulseLength, ...
            app.MinTotalkChange, ...
            app.MaxNumberOfIterations, ...
            app.MaxSAIterations, ...
            app.Sigmak0, ...
            app.kMin, ...
            app.kMax, ...
            app.MinThickness, ...
            app.UseFrictional, ...
            app.kAnisotropy, ...
            app.TopSensorDepth, app.ProgramLogId);

%         Inform user that the penetrationd data is being loaded in
%         ------------------------------------------------------------------
            figure(app.figure_Main)
            loading = uiprogressdlg(app.figure_Main,"Message",'Getting penetration data....');
            loading.Value = .2;
            pause(.5)

          if ~isfile(app.MATFile) 
%         Read in data from penetration TEXT file
%         -------------------------------------------------
            [app.StationName, ...
            app.Penetration, ...
            app.CruiseName, ...
            app.Datum, ...
            app.Latitude, ...
            app.Longitude, ...
            app.DepthMean, ...
            app.TiltMean, ...
            app.LoggerId, ...
            app.ProbeId, ...
            app.NumberOfSensors, ...
            app.PenetrationRecord, ...
            app.HeatPulseRecord, ...
            app.EndRecord, ...
            app.BottomWaterRawData, ...
            app.AllRecords, ...
            app.AllSensorsRawData, ...
            app.WaterSensorRawData, ...
            app.EqmStartRecord, ...
            app.EqmEndRecord, ...
            app.WaterThermistor...
            ] = ReadPenText(app.PenFile, app.figure_Main, ...
            app.WaterThermistor);

%         Read in information from tap TEXT file
%         -------------------------------------------------
            [app.TAPRecord, ...
            app.Tilt, app.Depth ...
            ] = ReadTAPText(app.LogFileId, app.ProgramLogId, app.ResFileId, ...
            app.TAPName, app.SensorDistance, app.DepthMean, app.TiltMean);

          else

%         Read in data from penetration MAT file
%         -------------------------------------------------
           
            [app.S_MATFile, app.FullExpeditionName, ...
            app.StationName, ...
            app.Penetration, ...
            app.CruiseName, ...
            app.Datum, ...
            app.Latitude, ...
            app.Longitude, ...
            app.DepthMean, ...
            app.TiltMean, ...
            app.LoggerId, ...
            app.ProbeId, ...
            app.NumberOfSensors, ...
            app.PenetrationRecord, ...
            app.HeatPulseRecord, ...
            app.EndRecord, ...
            app.BottomWaterRawData, ...
            app.AllRecords, ...
            app.AllSensorsRawData, ...
            app.WaterSensorRawData, ...
            app.EqmStartRecord, ...
            app.EqmEndRecord, ...
            app.WaterThermistor ...
            ] = ReadPenFile(app.MATFile, app.LogFileId, app.PenFile, ...
            app.ProgramLogId, app.figure_Main, app.WaterThermistor);


%         Read in information from tap MAT file
%         -------------------------------------------------
          [app.TAPRecord, ...
           app.Tilt, app.Depth ...
           ] = ReadTAPFile(app.S_MATFile, app.TAPFileName, ...
           app.LogFileId, app.ProgramLogId, app.ResFileId, app.SensorDistance);
          
          end

        % Set sensors to use to all sensors originally
        % ---------------------------------------------
        app.SensorsToUse = 1:app.NumberOfSensors;

        % If no water thermistor found (data is NaN), ignore it and set
        % WaterThermistor to 0
        % -------------------------------------------------------------
        if all(isnan(app.WaterSensorRawData))
            app.WaterSensorRawData = app.AllSensorsRawData(:, app.SensorsToUse(end));
            app.WaterThermistor = 0;
        end

%         Convert penetration file raw data to corrected temperature
%         -------------------------------------------------------------
           [app.BottomWaterTemp, ...
            app.WaterSensorTemp, ...
            app.AllSensorsTemp ...
            ] = TempCorrection(...
            app.BottomWaterRawData, ...
            app.AllSensorsRawData, ...
            app.WaterThermistor, ...
            app.WaterSensorRawData, ...
            app.WaterCorrectionType, app.Offset, ...
            app.PenetrationRecord, ...
            app.AllRecords, ...
            app.EqmStartRecord, ...
            app.EqmEndRecord, app.LogFileId, app.ProgramLogId, ...
            app.UseWaterSensor, ...
            app.NumberOfSensors, ...
            app.SensorsToUse, ...
            app.SensorsRemoved, ...
            app.figure_Main);


%         Split penetration and heat pulse data
%         -----------------------------------------
            [app.FricTime, ...
            app.FricTemp, ...
            app.PulseData, ...
            app.PulseTime, ...
            app.PulseTemp ...
            ] = SplitDecays( ...
            app.PenetrationRecord, ...
            app.HeatPulseRecord, ...
            app.EndRecord, ...   
            app.AllRecords, ...
            app.TimeScalingFactor, ...
            app.AllSensorsTemp, app.LogFileId,app.PenStartChanged, ...
            app.HeatPulseChanged, app.PenEndChanged, app.ProgramLogId, ...
            app.NumberOfColumns);

%           Update progress message
%           ---------------------------
            loading.Value = .7;
            loading.Message = 'Plotting penetration data...';
            pause(.5)

%           For checkboxes, all plots and checkboxes must have a value to
%           start
%           ------------------------------------------------------------
            app.h_axFricTempvTime = zeros(1, app.NumberOfSensors);
            app.h_axFricTempvTau = zeros(1, app.NumberOfSensors);
            app.h_axFricTempvTauPoints = zeros(1, app.NumberOfSensors);
            app.h_axFricTempvTauLines = zeros(1, app.NumberOfSensors);
            app.h_axFricRMSvTimeShift = zeros(1, app.NumberOfSensors);
            app.h_axFricRMSvTimeShiftMinDelays = zeros(1, app.NumberOfSensors);
            app.h_axHPTempvTime = zeros(1, app.NumberOfSensors);
            app.h_axHPRMS = zeros(1, app.NumberOfSensors);
            app.h_axHPRMSLine = zeros(1, app.NumberOfSensors);
            app.h_axHPTempvInvTime = zeros(1, app.NumberOfSensors);
            app.h_axHPTempvInvTimeBestFit = zeros(1, app.NumberOfSensors);
            app.h_axHPTempvTimeShift = zeros(1, app.NumberOfSensors);
            app.h_axHPTempvTimeShiftBestFit = zeros(1, app.NumberOfSensors);
            app.PenCheckboxes = zeros(1, app.NumberOfSensors);
            app.FricCheckboxes = zeros(1, app.NumberOfSensors);
            app.HPCheckboxes = zeros(1, app.NumberOfSensors);
            app.BMCheckboxes = zeros(1, app.NumberOfSensors);
            app.HFRCheckboxes = zeros(1, app.NumberOfSensors);
            app.SACheckboxes = zeros(1, app.NumberOfSensors);


%         Plot raw penetration data
%         ---------------------------
            [app.BadT, app.Badk, app.S_Lines,...
            app.AllSensors, app.h_axTempAboveBWT ...
            ] = PlotRawData(app.figure_Main, app.axes_TempAboveBWT, ...
            app.axes_BottomWaterTemp, ...
            app.axes_Depth, app.axes_Tilt, ...
            app.checkbox_BottomWaterPlot, ...
            app.checkbox_UseWS, ...
            app.checkbox_DepthPlot, app.checkbox_TiltPlot, ...
            app.AllSensorsTemp, ...
            app.NumberOfSensors, ...
            app.WaterThermistor, ...
            app.WaterSensorTemp, ...
            app.Tilt, app.TiltMean, ...
            app.Depth, app.DepthMean, ...
            app.TAPRecord, app.LogFileId, ...
            app.AllRecords, app.PenetrationRecord, app.HeatPulseRecord, app.EndRecord, ... 
            app.PulseData, app.ProgramLogId, app.grid_PenetrationInfo);

%         Plot sensor checkboxes 
%         ---------------------------
           [app.PenCheckboxes, ...
            app.FricCheckboxes, ...
            app.HPCheckboxes ...
          ] = PlotCheckboxes( ...
              app.SensorsToUse, ...
              app.NumberOfSensors, ...
              app.panel_PD_Thermistors, ...
              app.panel_FD_Thermistors, ...
              app.panel_HPD_Thermistors, ...
              app.panel_BA_Thermistors, ...
              app.panel_HF_Thermistors, ...
              app.panel_SA_Thermistors, ...
              app.h_axTempAboveBWT, ...
              app.h_axFricTempvTime, ...
              app.h_axFricTempvTau, ...
              app.h_axFricTempvTauPoints, ...
              app.h_axFricTempvTauLines, ...
              app.h_axFricRMSvTimeShift, ...
              app.h_axFricRMSvTimeShiftMinDelays, ...
              app.h_axHPTempvTime, ...
              app.h_axHPRMS, ...
              app.h_axHPRMSLine, ...
              app.h_axHPTempvInvTime, ...
              app.h_axHPTempvInvTimeBestFit, ...
              app.h_axHPTempvTimeShift, ...
              app.h_axHPTempvTimeShiftBestFit);


            % Update plot layout so that Bottom Water plot is hidden if no
            % bottom water sensor
            % --------------------------------------------------------------------
            app.BWPlot = app.checkbox_BottomWaterPlot.Value;
            app.DepthPlot = app.checkbox_DepthPlot.Value;
            app.TiltPlot = app.checkbox_TiltPlot.Value;
            
            plotLayout(app.grid_PenDataAxes, app.DepthPlot, app.axes_Depth, app.BWPlot,...
                app.axes_BottomWaterTemp, app.TiltPlot, app.axes_Tilt, app.TempPlot, ...
                app.axes_TempAboveBWT)
            
%           Update progress message
%           ---------------------------
            loading.Value = 1;
            loading.Message = 'Finishing...';
            pause(.5)
            
            % Update layout
            % -------------------------------------------
            updateLabels(app.label_currentpathfull, app.CurrentPath, app.label_calfilename, ...
            app.CalFileName, app.label_parfilename, app.ParFileName, app.label_penfilename, ...
            app.PenFileName, app.label_tapfilename, app.TAPFileName, app.label_resfilename, ...
            app.ResFileName, app.label_logfilename, app.LogFileName, app.label_Cruise, app.CruiseName, ...
            app.label_Station, app.StationName, app.label_Penetration, app.Penetration, ...
            app.label_Latitude, app.Latitude, app.label_Longitude, app.Longitude, app.edit_PenStart, ...
            app.PenetrationRecord, app.edit_PenEnd, app.EndRecord, app.edit_HP, app.HeatPulseRecord)
            
            app.checkbox_BottomWaterPlot.Value=0;
            app.checkbox_BottomWaterPlot.Value=1;
            pause(.5)

            % Align X axes of all visibile plots
            % ------------------------------------
            xAlign(app.DepthPlot, app.axes_Depth, app.BWPlot, app.axes_BottomWaterTemp, ...
                app.TiltPlot, app.axes_Tilt, app.TempPlot, app.axes_TempAboveBWT)

            pause(1);
            drawnow;

            app.tabgroup_Main.SelectedTab = app.tab_PenetrationData;

            % Enable pause between iteration if first trial (iterations are
            % not done after first trial)
            % ------------------------------------------------------------------
            if app.Trial == 0
                app.switch_PauseBetweenIter.Enable = 'on';
                app.NextIterationButton.Enable = 'on';
                app.EndButton.Enable = 'on';
            else
                app.NextIterationButton.Enable = 'off';
                app.EndButton.Enable = 'off';
            end

            figure(app.figure_Main)

            % If not heat pulse, remove HP data from GUI
            % --------------------------------------------
            if app.EndRecord > app.HeatPulseRecord
                app.PulseData = 1;
            else
                app.PulseData = 0;
                app.S_Lines.S_HPLines.HPLine.Visible = 'off';
                app.grid_DataControls.RowHeight = {'1x', '0x', '1x'};
            end
        end

        % Value changed function: checkbox_BottomWaterPlot
        function checkbox_BottomWaterPlotValueChanged(app, event)
            app.BWPlot = app.checkbox_BottomWaterPlot.Value;
            
            plotLayout(app.grid_PenDataAxes, app.DepthPlot, app.axes_Depth, app.BWPlot,...
                app.axes_BottomWaterTemp, app.TiltPlot, app.axes_Tilt, app.TempPlot, ...
                app.axes_TempAboveBWT)
            
            % Align X axes of all visibile plots
            % ------------------------------------
            xAlign(app.DepthPlot, app.axes_Depth, app.BWPlot, app.axes_BottomWaterTemp, ...
                app.TiltPlot, app.axes_Tilt, app.TempPlot, app.axes_TempAboveBWT)
        end

        % Value changed function: checkbox_DepthPlot
        function checkbox_DepthPlotValueChanged(app, event)
            app.DepthPlot = app.checkbox_DepthPlot.Value;
            
            plotLayout(app.grid_PenDataAxes, app.DepthPlot, app.axes_Depth, app.BWPlot,...
                app.axes_BottomWaterTemp, app.TiltPlot, app.axes_Tilt, app.TempPlot, ...
                app.axes_TempAboveBWT)
            
            % Align X axes of all visibile plots
            % ------------------------------------
            xAlign(app.DepthPlot, app.axes_Depth, app.BWPlot, app.axes_BottomWaterTemp, ...
                app.TiltPlot, app.axes_Tilt, app.TempPlot, app.axes_TempAboveBWT)
        end

        % Value changed function: checkbox_TiltPlot
        function checkbox_TiltPlotValueChanged(app, event)
            app.TiltPlot = app.checkbox_TiltPlot.Value;
            
            plotLayout(app.grid_PenDataAxes, app.DepthPlot, app.axes_Depth, app.BWPlot,...
                app.axes_BottomWaterTemp, app.TiltPlot, app.axes_Tilt, app.TempPlot, ...
                app.axes_TempAboveBWT)
           
            % Align X axes of all visibile plots
            % ------------------------------------
            xAlign(app.DepthPlot, app.axes_Depth, app.BWPlot, app.axes_BottomWaterTemp, ...
                app.TiltPlot, app.axes_Tilt, app.TempPlot, app.axes_TempAboveBWT)
        end

        % Button pushed function: button_SetlParams
        function button_SetlParamsPushed(app, event)
            app.button_SetlParams.Enable = 'off';

            app.SetParamsDialogApp = SetParams(app,app.WaterCorrectionType, ...
            app.Offset, ...
            app.NumberOfSensors, ...	
            app.WaterThermistor, ...
            app.TimeScalingFactor, ...
            app.DeltaTime, ...
            app.SensorRadius, ...
            app.SensorDistance, ...
            app.TempError, ...
            app.HyndmanCoeffs, ...
            app.FrictionalDelays, ...
            app.FricMaxStep, ...
            app.TimeInc, ...
            app.FricTauMin, ...
            app.FricTauMax, ...
            app.PulseDelays, ...
            app.kInit, ...
            app.PulsePower, ...
            app.TimeShiftInit, ...
            app.TimeShiftInc, ...
            app.PulseMaxStep, ...
            app.kTolerance, ...
            app.PulseTauMin, ...
            app.PulseTauMax, ...
            app.HeatPulseLength, ...
            app.MinTotalkChange, ...
            app.MaxNumberOfIterations, ...
            app.MaxSAIterations, ...
            app.Sigmak0, ...
            app.kMin, ...
            app.kMax, ...
            app.MinThickness, ...
            app.UseFrictional, ...
            app.kAnisotropy, ...
            app.TopSensorDepth);

            app.button_SetlParams.Enable = 'on';

            if ~app.WaterThermistor
                app.UseWaterSensor.Enable = 'off';
            end

            save SlugHeat22Temp
        end

        % Button pushed function: button_Process
        function button_ProcessButtonPushed(app, event)
% ===========================================
%       FLAG IF NO PENETRATION SELECTED
% ===========================================

if ~exist(app.PenFile, 'file')
        uialert(app.figure_Main, 'Must select a penetration', 'ERROR', ...
            'Icon','error')
        return
else

% ======================================
%       UPDATE before processing
% ======================================

%           Update start of processing time
%           ---------------------------------
            app.label_ProcessingTime.Text = datestr(datetime('now'));
            PrintStatus(app.LogFileId, repmat('-',1,app.NumberOfColumns),1)
            PrintStatus(app.LogFileId, [datestr(datetime('now')) ' - Begin SlugHeat22 PROCESSING'], 1)
               PrintStatus(app.ProgramLogId, '==================================================================================================',2);

            PrintStatus(app.ProgramLogId, [datestr(datetime('now')) ' - Begin SlugHeat22 PROCESSING'], 1)
	        PrintStatus(app.ProgramLogId, '==================================================================================================',2);

%           Update record numbers for frictional decay and heat pulse decay
%           in case manually changed by user
%           ---------------------------------------------------
            [app.FricTime, ...
            app.FricTemp, ...
            app.PulseData, ...
            app.PulseTime, ...
            app.PulseTemp ...
            ] = SplitDecays( ...
            app.PenetrationRecord, ...
            app.HeatPulseRecord, ...
            app.EndRecord, ...   
            app.AllRecords, ...
            app.TimeScalingFactor, ...
            app.AllSensorsTemp, app.LogFileId, app.PenStartChanged, ...
            app.HeatPulseChanged, app.PenEndChanged, app.ProgramLogId, ...
            app.NumberOfColumns);

%           Update LOG files with current record numbers, tau winddows,
%           and convergence criteria
%           -----------------------------------------------------------
            PrintStatus(app.LogFileId,'Selected processing parameters:',1);
            PrintStatus(app.LogFileId,'-------------------------------',2);

            PrintStatus(app.LogFileId,['Penetration Record:  ' int2str(app.PenetrationRecord)],1);
            PrintStatus(app.LogFileId,['Heat Pulse Record:   ' int2str(app.HeatPulseRecord)],1);
            PrintStatus(app.LogFileId,['End of Data Record:  ' int2str(app.EndRecord)],2);
            PrintStatus(app.LogFileId,['Frictional Tau Window:  [' ...
            num2str(app.FricTauMin) '  ' num2str(app.FricTauMax) ']'],1);
            PrintStatus(app.LogFileId,['Heat Pulse Tau Window:  [' ...
            num2str(app.PulseTauMin) '  ' num2str(app.PulseTauMax) ']'],2);
            PrintStatus(app.LogFileId,['Max # of Iterations:     ' int2str(app.MaxNumberOfIterations)],1);
            PrintStatus(app.LogFileId,['Min change in Total k:   ' num2str(app.MinTotalkChange)],3); 

 %          Clear current plots
 %          --------------------

                % Heat Flow Analysis axes
                % ----------------------
                cla(app.axes_TempvDepth, 'reset')
                cla(app.axes_TCvDepth, 'reset')
                cla(app.axes_TempvCTR, 'reset')
                app.label_gradient.Text = '';
                app.label_depthoftop.Text = '';
                app.label_averagek.Text = '';
                app.label_heatflow.Text = ''; 
                app.label_shift.Text = '';

                % Heat Flow Regression
                % ----------------------
                cla(app.axes_HeatFlow, 'reset')
                cla(app.axes_Sigma, 'reset')

                % Reset all plots for senstivity analysis
                % -----------------------------------------
                delete(app.axes_SA_TCvDepth.Children)
                delete(app.axes_SA_TempvCTR.Children)
                delete(app.axes_SA_SDvIter.Children)
            
            %% Frictional Decay %
            %delete(app.axes_TempvTime.Children)
            %delete(app.axes_TempvTau.Children)
            %delete(app.axes_TempvBullFunc.Children)
            %delete(app.axes_TimeShift.Children)

            app.grid_FricDecayAxes.RowHeight = {'1x', '1x'};
            app.grid_FricDecayAxes.ColumnWidth = {'1x', '1x'};

            %% Heat Pulse Decay %
            %delete(app.axes_CTempvCtime.Children)
            %delete(app.axes_CTempv1_CTime.Children)
            %delete(app.axes_MisfitvTimeShift.Children)
            %delete(app.axes_TempvTimeShift.Children)

            app.grid_HPDecayAxes.RowHeight = {'1x', '1x'};
            app.grid_HPDecayAxes.ColumnWidth = {'1x', '1x'};

            %% Heat Flow Analysis %
            %delete(app.axes_TempvDepth.Children)
            %delete(app.axes_TCvDepth.Children)
            %delete(app.axes_TempvCTR.Children)

            app.grid_BullAnalysisAxes.RowHeight = {'1x'};
            app.grid_BullAnalysisAxes.ColumnWidth = {'1x', '1x', '1x'};

%           Use heat pulse decay reduction or theoretical thermal
%           conductivity values?
            if app.checkbox_UseHP.Value == 1 && app.PulseData == 1
                app.PulseData = 1;
                HPStatus = 'Used';
            else
                app.PulseData = 0;
                HPStatus = 'Not Used';
            end

% ======================================================
%       ITERATIVE or INVERSION processing method?
% ======================================================
%MethodChoice = uiconfirm(app.figure_Main, ...
%    'Which processing method would you like to use to calculate thermal conductivity?', ...
%    'PROCESSING METHOD', 'Icon', 'question', 'Options', {'Iterate', ...
%    'Full Inversion'});

MethodChoice = 'Iterate';

switch MethodChoice
    case 'Iterate'

        if app.Trial == 0 || (app.Trial > 0 && ~app.PulseData) || app.Reprocess
% ===============================================================
%       INITIALIZE main loop for processing via ITERATIVE method
% ===============================================================

            [app.A, ...
            app.B, ...
            app.C, ...
            app.D, ...
            app.E, ...
            app.F, ...
            app.kFunction, ...
            app.Currentk, ...
            app.CurrentT, ...
            app.TChange, ...
            app.kChange, ...
            app.Iteration, ...
            app.FirstIteration, ...
            app.TotalIterations, ...
            app.AnotherTrial, ...
            app.Trial, ...
            app.RelativeDepths ...
            ] = InitializeProcessing(app.NumberOfSensors, ...
            app.SensorDistance, ...
            app.TopSensorDepth, ...
            app.kInit, app.Trial);
            
        end
% ============================================================
%       BEGIN main loop for processing via ITERATIVE method
% ============================================================

app.label_trial.Text = num2str(app.Trial);
           
%         Begin iterative part of the computations
%         ----------------------------------------
           if app.Trial == 1 || ~app.PulseData  ||  ...
                    app.Reprocess 
              
              if app.Trial > 1
                %        Initialize Counters
                %        -------------------
                    
                         app.Iteration = 1;
              end

              IterationCounter=1;

              loading = uiprogressdlg(app.figure_Main,"Message",'Processing frictional decay and heat pulse decay data');
              loading.Value = .3;

              ChosenIteration = app.MaxNumberOfIterations;
               
               while (app.Iteration <= app.MaxNumberOfIterations ...
                       && abs(sum(app.kChange(find(~isnan(app.kChange))))) >= app.MinTotalkChange) 

                   app.label_Iteration.Text = num2str(app.Iteration);

                   loading = uiprogressdlg(app.figure_Main,"Message",['Processing: Iteration ' num2str(app.Iteration)]);
                   loading.Value = .4;
                  

                   % ====================================================================
                   % COMPUTE ASYMPTOTIC TEMPERATURES AFTER FRICTIONAL PENETRATION DECAY
                   % ====================================================================
                   
                  if (app.Trial == 1 || (app.Trial > 1 && app.kFunction))
                       
                      app.tabgroup_Main.SelectedTab = app.tab_FricitonalDecayReduction;
                       
                       % Compute frictional decay function
                       % ---------------------------------
                        [app.NumberOfFricUsedPoints, ...
                                app.MinimumFricEqTemp, ...
                                app.MinimumFricError, ...
                                app.MinimumFricDelays, ...
                                app.MinimumFricSlope, ...
                                app.HPTooLow, ...
                                app.ShiftedTime, ...
                                app.IndexOfMinimums, ...
                                app.DataTemp, ...
                                app.TimeShifts, ...
                                app.ShiftedTau, ...
                                app.DataFAT, ...
                                app.DataLimits, ...
                                app.b, ...
                                app.a, ...
                                app.RMS ...
                                ] = FrictionalDecay( ...
                                app.figure_Main, ...
                                app.FricTime, ...
                                app.FricTemp, ...
                                app.NumberOfSensors, ...
                                app.FrictionalDelays, ...
                                app.FricMaxStep, ...
                                app.TimeInc, ...
                                app.Currentk, ...
                                app.HyndmanCoeffs, ...
                                app.SensorRadius, ...
                                app.FricTauMin, ...
                                app.FricTauMax, ...
                                app.SensorsToUse, ...
                                loading);

                        % Plot frictional decay reduction results
                        % ---------------------------------------
                        [app.h_axFricTempvTime, ...
                                app.h_axFricTempvTau, ...
                                app.h_axFricTempvTauPoints, ...
                                app.h_axFricTempvTauLines, ...
                                app.h_axFricRMSvTimeShift, ...
                                app.h_axFricRMSvTimeShiftMinDelays ...
                                ] = FrictionalDecayPlot(app.NumberOfSensors, ...
                                app.h_axTempAboveBWT, ...
                                app.axes_TempvTime, ...
                                app.axes_TempvTau, ...
                                app.axes_TempvBullFunc,...
                                app.axes_TimeShift, ...
                                app.grid_FricDecayAxes, ...
                                app.Iteration, ...
                                ChosenIteration, ...
                                app.Pause, ...
                                app.PulseData, ...
                                app.SensorsToUse, ...
                                app.ShiftedTime, ...
                                app.IndexOfMinimums, ...
                                app.DataTemp, ...
                                app.FricTime, ...
                                app.TimeShifts, ...
                                app.ShiftedTau, ...
                                app.FricTauMax, ...
                                app.FricTauMin, ...
                                app.DataFAT, ...
                                app.DataLimits, ...
                                app.b, ...
                                app.a, ...
                                app.MinimumFricEqTemp, ...
                                app.RMS, ...
                                app.MinimumFricDelays);

                       % Print frictional decay reduction results to LOG
                       % and RES files
                       % ----------------------------------------
                       PrintFricResults(app.NumberOfSensors, ...
                           app.IgnoredSensors, ...
                           app.FricTime, ...
                           app.NumberOfFricUsedPoints, ...
                           app.MinimumFricEqTemp, ...
                           app.MinimumFricError, ...
                           app.SensorDistance, ...
                           app.MinimumFricDelays, ...
                           app.MinimumFricSlope, ...
                           app.TChange, ...
                           app.ResFileId, ...
                           app.LogFileId, ...
                           app.NumberOfColumns, ...
                           app.Iteration, ...
                           app.Trial, ...
                           app.PulseData, ...
                           app.SensorsToUse)

                       %define new equilibrium sediment temperature%
                        app.PreviousT = app.CurrentT;
                        app.CurrentT = app.MinimumFricEqTemp';
                   end

                   % ==========================================================================
                   % IF THERE IS A HEAT PULSE --> COMPUTE THERMAL
                   % CONDUCTIVITIES FROM HEAT PULSE DECAY
                   % ==========================================================================
                  if app.PulseData 


                       %heat pulse decay function%
                       [ ...
                        app.MeankPointAtMinkDiff, ...
                        app.kSlopeAtMinkDiff, ...
                        app.MeankPointAtZeroInfTemp, ...
                        app.MeankPointAtMinRMS, ...
                        app.kSlopeAtZeroInfTemp, ...
                        app.kSlopeAtMinRMS, ...
                        app.TempAtInf, ...
                        app.NumberOfUsedPoints, ...
                        app.MinimumPulseDelays, ...
                        app.kError, ...
                        app.h_axHPTempvTime, ...
                        app.h_axHPRMS, ...
                        app.h_axHPRMSLine, ...
                        app.h_axHPTempvInvTime, ...
                        app.h_axHPTempvInvTimeBestFit, ...
                        app.h_axHPTempvTimeShift, ...
                        app.h_axHPTempvTimeShiftBestFit ...
                        ] = HeatPulseDecay( ...
                            app.NumberOfSensors, ...
                            app.SensorsToUse, ...
                            app.PulseTime, ...
                            app.PulseDelays, ...
                            app.MinimumFricEqTemp, ...
                            app.MinimumFricSlope, ...
                            app.PulseTemp, ...
                            app.Currentk, ...
                            app.HyndmanCoeffs, ...
                            app.SensorRadius, ...	
                            app.PulseMaxStep, ...
                            app.TimeShiftInc, ...
                            app.PulseTauMin, ...
                            app.PulseTauMax, ...
                            app.PulsePower, ...
                            app.HeatPulseLength, ...
                            app.axes_CTempvCtime, ...
                            app.axes_CTempv1_CTime, ...
                            app.axes_MisfitvTimeShift, ...
                            app.axes_TempvTimeShift, ...
                            app.axes_HeatPulseTesting, ...
                            app.h_axTempAboveBWT, ...
                            app.Iteration, ...
                            ChosenIteration, ...
                            app.Pause);  

                
                       %write heat pulse results to results file%

                        PrintHeatPulseResults( ...
                            app.NumberOfSensors, ...
                            app.IgnoredSensors, ...
                            app.PulseTime, ...
                            app.NumberOfUsedPoints, ...
                            app.MeankPointAtMinkDiff, ...
                            app.kError, ...
                            app.TempAtInf, ...
                            app.MinimumPulseDelays, ...
                            app.kSlopeAtMinkDiff, ...
                            app.MeankPointAtZeroInfTemp, ...
                            app.MeankPointAtMinRMS, ...
                            app.kSlopeAtMinRMS, ...
                            app.kSlopeAtZeroInfTemp, ...
                            app.Iteration, ...
                            app.kChange, ...
                            app.LogFileId, ...
                            app.SensorsToUse)

                       %define new conductivity%
                        app.Previousk = app.Currentk;
                        app.Currentk  = app.MeankPointAtMinkDiff';
                   else

                   % ==========================================================================
                   % DO HEAT FLOW ANALYSIS FOR CASE OF NO HEAT PULSE
                   % ==========================================================================
                        app.tabgroup_Main.SelectedTab = app.tab_HeatFlowAnalysis;

                       %Heat Flow analysis function%
                       [ ...
                         app.SensorsUsedForBullardFit, ...
                         app.GoodkIndex, ...
                         app.CTRToUse, ...
                         app.CTR, ...
                         app.ShiftedCTR, ...
                         app.ShiftedRelativeDepths, ...
                         app.SigmaR, ...
                         app.PenetrationLag, ...
                         app.Slope, ...
                         app.Shift, ...
                         app.S_BullPlots, ...
                         app.HeatFlow, ...
                         app.Averagek, ...
                         app.Gradient] = HeatFlowAnalysis(app.NumberOfSensors, ...
                         app.RelativeDepths, ...
                         app.Currentk, ...
                         app.MinimumFricEqTemp, ...
                         app.Badk, ...
                         app.BadT, ...
                         app.h_axTempAboveBWT, ...
                         app.SensorsToUse, ...
                         app.axes_TempvDepth, ...
                         app.axes_TCvDepth, ...
                         app.axes_TempvCTR, ...
                         app.label_gradient, ...
                         app.label_depthoftop, ...
                         app.label_averagek, ...
                         app.label_heatflow, ...
                         app.label_shift);

                        PrintBullardResults(app.ResFileId, ...
                        app.Iteration, ...
                        app.Trial, ...
                        app.PulseData, ...
                        app.TChange, ...
                        app.SensorsToUse, ...
                        app.ShiftedRelativeDepths, ...
                        app.MinimumFricEqTemp, ...
                        app.BottomWaterTemp, ...
                        app.MinimumFricError, ...
                        app.Currentk, ...
                        app.HeatFlow)

                       %define new conductivty%
                        app.Previousk = app.Currentk;

                        if app.kFunction
                            app.Currentk = app.A + app.B*app.ShiftedRelativeDepths + app.C*app.ShiftedRelativeDepths.^2 ...
                            + app.D*exp(app.E*app.ShiftedRelativeDepths) + app.F*log10(app.ShiftedRelativeDepths);
                        else
                            app.Currentk = app.kInit;
                        end

                        if app.Iteration == 1 
                            delete(app.axes_TCvDepth.Children)
                            delete(app.axes_TempvCTR.Children)
                        end
                   
                   end

                   % Update temperature and conductivity 
                   % -------------------------------------------------
                    app.TChange = abs(app.CurrentT(~isnan(app.CurrentT))-app.PreviousT(~isnan(app.CurrentT)));
                    app.kChange = abs(app.Currentk(~isnan(app.Currentk))-app.Previousk(~isnan(app.Currentk)));
                   

                   % Next iteration
                   % ----------------------------------------------
                    app.Iteration = app.Iteration + 1;
                    app.TotalIterations = app.TotalIterations + 1;
                    IterationCounter = IterationCounter +1;

                    app.FirstIteration = 0;
                   
                   if app.PulseData
                    app.tabgroup_Main.SelectedTab = app.tab_HeatPulseDecayReduction;
                   else
                    app.tabgroup_Main.SelectedTab = app.tab_FricitonalDecayReduction;
                   end
                   pause(.5)
                   drawnow;

                   if app.Pause == 1
                       close(loading)
                       uiwait(app.figure_Main)
                   end

                   if app.Converged == 1
                       break
                   end

                   if (app.Iteration <= app.MaxNumberOfIterations ...
                       && abs(sum(app.kChange(find(~isnan(app.kChange))))) >= app.MinTotalkChange) 
                   % Delete plots from Heat Flow Analysis except last iteration %
                   % ----------------------------------------------------------
                   delete(app.axes_TempvDepth.Children)
                   delete(app.axes_TCvDepth.Children)
                   delete(app.axes_TempvCTR.Children)
                   end
               end


           end

           app.tabgroup_Main.SelectedTab = app.tab_HeatFlowAnalysis;
            
           
           loading = uiprogressdlg(app.figure_Main,"Message", 'Plotting frictional decay and heat pulse decay data');
           loading.Value = .6;   
           % ==========================================================================
           % DO HEAT FLOW ANALYSIS FOR CASE OF HEAT PULSE
           % ==========================================================================
           if app.PulseData %|| app.NoInitialize == 1
               loading.Message = 'Calculating heat flow';
               loading.Value = .7;
        
               app.tabgroup_Main.SelectedTab = app.tab_HeatFlowAnalysis;
               
               %Heat Flow Analysis function%

                       [ ...
                         app.SensorsUsedForBullardFit, ...
                         app.GoodkIndex, ...
                         app.CTRToUse, ...
                         app.CTR, ...
                         app.ShiftedCTR, ...
                         app.ShiftedRelativeDepths, ...
                         app.SigmaR, ...
                         app.PenetrationLag, ...
                         app.Slope, ...
                         app.Shift, ...
                         app.S_BullPlots, ...
                         app.HeatFlow, ...
                         app.Averagek, ...
                         app.Gradient] = HeatFlowAnalysis(app.NumberOfSensors, ...
                         app.RelativeDepths, ...
                         app.Currentk, ...
                         app.MinimumFricEqTemp, ...
                         app.Badk, ...
                         app.BadT, ...
                         app.h_axTempAboveBWT, ...
                         app.SensorsToUse, ...
                         app.axes_TempvDepth, ...
                         app.axes_TCvDepth, ...
                         app.axes_TempvCTR, ...
                         app.label_gradient, ...
                         app.label_depthoftop, ...
                         app.label_averagek, ...
                         app.label_heatflow, ...
                         app.label_shift);

                        PrintBullardResults(app.ResFileId, ...
                        app.Iteration, ...
                        app.Trial, ...
                        app.PulseData, ...
                        app.TChange, ...
                        app.SensorsToUse, ...
                        app.ShiftedRelativeDepths, ...
                        app.MinimumFricEqTemp, ...
                        app.BottomWaterTemp, ...
                        app.MinimumFricError, ...
                        app.Currentk, ...
                        app.HeatFlow)

              loading.Message = 'Plotting Heat Flow plots';
              loading.Value = .8;
          end
           % ==========================================================================
           % NOW DO HEAT FLOW REGRESSION ANALYSIS
           % ==========================================================================
                
                loading.Message = 'Processing heat flow regresion analysis';
                loading.Value = .9;

                %Heat flow regression analysis function%
                [app.Scatter, ...
                app.TempOverDepth, ...
                app.Sigmaa, ...
                app.Sigmab, ...
                app.HFLine, ...
                app.ScatterLine, ...
                app.SigmaHFLine, ...
                app.SigmaScatterLine] =  HeatFlowRegression(...
                    app.axes_HeatFlow, ...
                    app.axes_Sigma, ...
                    app.SensorsUsedForBullardFit, ...
                    app.GoodkIndex, ...
                    app.ShiftedRelativeDepths, ...
                    app.ShiftedCTR, ...
                    app.MinimumFricEqTemp, ...
                    app.MinimumFricError, ...
                    app.Currentk, ...
                    app.NumberOfSensors, ...
                    app.Trial, ...
                    app.ResFileName);

               % if app.Trial
                   % eval(['save ' ResName '_Summary' ...
                   %sensors' SensorsUsedForBullardFit' ...
                   %sensors' GoodkIndex' ...
                   %sensors' ShiftedRelativeDepths' ...
                   %sensors' ShiftedCTR' ...
                   %sensors' Currentk' ...
                   %sensors' MinimumFricEqTemp' ...
                   %sensors' MinimumFricError' ...
                   %sensors' Iteration' ...
                   %sensors' PenetrationLag' ...
                   %sensors' Slope' ...
                   %sensors' Shift' ...
                   %sensors' SigmaR' ...
                   %sensors' NumberOfFricUsedPoints' ...
                   %sensors' MinimumFricDelays' ...
                   %sensors' MinimumFricSlope' ...
                   %sensors' Scatter' ...
                   %sensors' TempOverDepth']);
               % end

                % Update LOG and RESULTS File
                % ----------------------------
               %PrintStatus(app.LogFileId,['Saved mat file: ' ResName ...
               %    '_Summary.mat with summary of results after Trial 1 ...'],2);
%
               %PrintStatus(app.LogFileId,[datestr(datetime('now')) ...
               %    ' - End processing of Trial ' int2str(app.Trial) ' !'],1);
               %PrintStatus(app.LogFileId,[repmat('-',1,app.NumberOfColumns)],3);
               %PrintStatus(app.ResFileId,[datestr(datetime('now')) ...
               %    ' - End processing of Trial ' int2str(app.Trial) ' !'],1);
               %PrintStatus(app.ResFileId,[repmat('-',1,app.NumberOfColumns)],3);  
%
               %app.edit_Iteration.Value = app.Iteration;
               %end


    %case 'Full Inversion'
        % ....
                
end

% ==========================================================================
%                   FINALIZE
% ==========================================================================

%         Reset Reprocess variable to 0 so that only when you want to fully
%         re-calculate eq. temp and therm. con. will the
%         full iteration repeatgf
%         ---------------------------------------------------------------
          app.Reprocess = 0;

%         Enable sensitivity analysis
%         ----------------------------
          app.button_SensAn.Enable = 'on';

%         Update sensor checkboxes with information from new axes
%         ------------------------------------------------------
            [app.PenCheckboxes, ...
            app.FricCheckboxes, ...
            app.HPCheckboxes ...
            ] = PlotCheckboxes( ...
              app.SensorsToUse, ...
              app.NumberOfSensors, ...
              app.panel_PD_Thermistors, ...
              app.panel_FD_Thermistors, ...
              app.panel_HPD_Thermistors, ...
              app.panel_BA_Thermistors, ...
              app.panel_HF_Thermistors, ...
              app.panel_SA_Thermistors, ...
              app.h_axTempAboveBWT, ...
              app.h_axFricTempvTime, ...
              app.h_axFricTempvTau, ...
              app.h_axFricTempvTauPoints, ...
              app.h_axFricTempvTauLines, ...
              app.h_axFricRMSvTimeShift, ...
              app.h_axFricRMSvTimeShiftMinDelays, ...
              app.h_axHPTempvTime, ...
              app.h_axHPRMS, ...
              app.h_axHPRMSLine, ...
              app.h_axHPTempvInvTime, ...
              app.h_axHPTempvInvTimeBestFit, ...
              app.h_axHPTempvTimeShift, ...
             app.h_axHPTempvTimeShiftBestFit);

%       Close loading message
%       -----------------------

        loading.Message = 'Finishing processing';
        loading.Value = 1;
        close(loading)
        
        % Update plot colors
        % -------------------
        app.axes_TempvTime.Color = app.LightGrey;
        app.axes_TempvBullFunc.Color = app.LightGrey;
        app.axes_TempvTau.Color = app.LightGrey;
        app.axes_TimeShift.Color = app.LightGrey;
        
        app.axes_TempvTimeShift.Color = app.LightGrey;
        app.axes_MisfitvTimeShift.Color = app.LightGrey;
        app.axes_CTempv1_CTime.Color = app.LightGrey;
        app.axes_CTempvCtime.Color = app.LightGrey;
        
        app.axes_TempvCTR.Color = app.LightGrey;
        app.axes_TCvDepth.Color = app.LightGrey;
        app.axes_TempvDepth.Color = app.LightGrey;
        
        app.axes_HeatFlow.Color = app.LightGrey;
        app.axes_Sigma.Color = app.LightGrey;


%       Save results to summary table
%       -------------------------------
        
        Cruise = app.CruiseName;
        Station = num2str(app.StationName);
        Pen = num2str(app.Penetration);
        Iter = num2str(app.Iteration-1);
        HP = num2str(app.PulsePower);
        HP = [HPStatus, ' (',HP, ' J/m)'];
        TrialNum = num2str(app.Trial);
        TotNumSens = num2str(app.NumberOfSensors);
        IgnoredSens = strjoin(string(app.IgnoredSensors));
        SensorsUsed = strjoin(string(app.SensorsToUse));
        SensorsTemp = setdiff(app.SensorsToUse, app.BadT);
        SensorsTemp = strjoin(string(SensorsTemp));
        Sensorsk = setdiff(app.SensorsToUse, app.Badk);
        Sensorsk = strjoin(string(Sensorsk));
        TiltPen = num2str(round(mean(app.Tilt), 1));
        Grad = num2str(round(app.Gradient,3));
        Avk = num2str(round(app.Averagek,3));
        HF = app.HeatFlow;
        Notes = '';

        % Heat Flow Summary Table
        app.HFData = [Cruise, Station, Pen, TrialNum, Iter, HP, TotNumSens, IgnoredSens, TiltPen, Grad, Avk, HF, Notes];
        
        app.table_HFSummary.Data = [app.table_HFSummary.Data; app.HFData];
        
        app.button_DiscardSensors.Enable = 'on';

        HFResultsSummary = app.table_HFSummary;

        save('HFResultsSummaryTable', "HFResultsSummary");

        % Begin Error and Analysis Table
        app.ErrData = {Cruise, Station, Pen, TrialNum};

        PrintStatus(app.LogFileId, app.PenNotes, 2)
        PrintStatus(app.ResFileId, app.PenNotes, 2)

%       Enable pause between iteration if first trial (iterations are
%       not done after first trial)
%       ------------------------------------------------------------------
        if app.Trial == 0
            app.switch_PauseBetweenIter.Enable = 'on';
            app.NextIterationButton.Enable = 'on';
            app.EndButton.Enable = 'on';
        else
            app.NextIterationButton.Enable = 'off';
            app.EndButton.Enable = 'off';
        end

%       Alert user that .log and .res output files are created
%       ------------------------------------------------------------------        
           uialert(app.figure_Main, ['Output files created for penetration: ' newline ...
               app.LogFileName newline ...
               app.ResFileName newline], ...
               'Success', 'Icon','success')
end
        end

        % Button pushed function: button_Restart
        function button_RestartPushed(app, event)

%         Reset all
%         ------------------------------------------
            app.SensorsToUse = app.AllSensors;
            app.Trial = 0;
            app.Reprocess = 0;
            app.SensorsRemoved = 0;
            app.UseWaterSensor = 1;
            app.WaterThermistor = 1;
            app.UseHP = 1;

            ResetAll(app.axes_TempAboveBWT, app.axes_BottomWaterTemp, ...
            app.axes_Depth, app.axes_Tilt, app.axes_TempvTime, app.axes_TempvTau, ...
            app.axes_TempvBullFunc, app.axes_TimeShift, app.axes_CTempvCtime, ...
            app.axes_CTempv1_CTime, app.axes_MisfitvTimeShift, app.axes_TempvTimeShift, ...
            app.axes_TempvDepth, app.axes_TCvDepth, app.axes_TempvCTR, ...
            app.axes_HeatFlow, app.axes_Sigma, ...
            app.label_gradient, app.label_depthoftop, app.label_averagek, app.label_heatflow, app.label_shift, ...
            app.label_trial, app.label_Iteration, app.label_currentpathfull, ...
            app.label_parfilename, app.label_calfilename, app.label_penfilename, app.label_tapfilename, ...
            app.label_logfilename, app.label_resfilename, ...
            app.edit_PenStart, app.edit_HP, app.edit_PenEnd, ...
            app.checkbox_BottomWaterPlot, app.checkbox_DepthPlot, ...
            app.checkbox_TiltPlot, app.PenCheckboxes, app.FricCheckboxes, app.HPCheckboxes, ...
            app.checkbox_UseWS, app.checkbox_UseHP, ...
            app.S_MATFile, app.NumberOfSensors, app.ProgramLogId, app.SensorsToUse)

            % Reset all plots for senstivity analysis
            % -----------------------------------------
            delete(app.axes_SA_TCvDepth.Children)
            delete(app.axes_SA_TempvCTR.Children)
            delete(app.axes_SA_SDvIter.Children)

        end

        % Value changed function: edit_PenStart
        function edit_PenStartValueChanged(app, event)
            app.PenetrationRecord = app.edit_PenStart.Value;

            delete(app.S_Lines.S_PenLines.PenLine)
            delete(app.S_Lines.S_PenLines.PenBWTLine)
            delete(app.S_Lines.S_PenLines.PenDepthLine)
            delete(app.S_Lines.S_PenLines.PenTiltLine)

            app.S_Lines.S_PenLines.PenLine = xline(app.axes_TempAboveBWT, app.PenetrationRecord, '--k');
            app.S_Lines.S_PenLines.PenLine.Label = 'Start of Penetration';
            app.S_Lines.S_PenLines.PenLine.FontSize = 16;
            app.S_Lines.S_PenLines.PenLine.FontWeight = 'bold';
            app.S_Lines.S_PenLines.PenBWTLine = xline(app.axes_BottomWaterTemp, app.PenetrationRecord, '--k');
            app.S_Lines.S_PenLines.PenDepthLine = xline(app.axes_Depth, app.PenetrationRecord, '--k');
            app.S_Lines.S_PenLines.PenTiltLine = xline(app.axes_Tilt, app.PenetrationRecord, '--k');

            app.PenStartChanged=1;

            save SlugHeat22Temp
        end

        % Value changed function: edit_HP
        function edit_HPValueChanged(app, event)
            if ~app.PulseData
                app.edit_HP.Enable = 'off';
            else
                value = app.edit_HP.Value;
                app.HeatPulseRecord = value;

                delete(app.S_Lines.S_HPLines.HPLine)
                delete(app.S_Lines.S_HPLines.HPBWTLine)
                delete(app.S_Lines.S_HPLines.HPDepthLine)
                delete(app.S_Lines.S_HPLines.HPTiltLine)

                app.S_Lines.S_HPLines.HPLine = xline(app.axes_TempAboveBWT, app.HeatPulseRecord, '--k');
                app.S_Lines.S_HPLines.HPLine.Label = 'Heat Pulse';
                app.S_Lines.S_HPLines.HPLine.FontSize = 16;
                app.S_Lines.S_HPLines.HPLine.FontWeight = 'bold';
                app.S_Lines.S_HPLines.HPBWTLine = xline(app.axes_BottomWaterTemp, app.HeatPulseRecord, '--k');
                app.S_Lines.S_HPLines.HPDepthLine = xline(app.axes_Depth, app.HeatPulseRecord, '--k');
                app.S_Lines.S_HPLines.HPTiltLine = xline(app.axes_Tilt, app.HeatPulseRecord, '--k');
            end
            
            app.HeatPulseChanged=1;

            save SlugHeat22Temp
        end

        % Value changed function: edit_PenEnd
        function edit_PenEndValueChanged(app, event)
            app.EndRecord = app.edit_PenEnd.Value;

            delete(app.S_Lines.S_EndPenLines.EndLine)
            delete(app.S_Lines.S_EndPenLines.EndBWTLine)
            delete(app.S_Lines.S_EndPenLines.EndDepthLine)
            delete(app.S_Lines.S_EndPenLines.EndTiltLine)

            app.S_Lines.S_EndPenLines.EndLine = xline(app.axes_TempAboveBWT, app.EndRecord, '--k');
            app.S_Lines.S_EndPenLines.EndLine.Label = 'End of Penetration';
            app.S_Lines.S_EndPenLines.EndLine.FontSize = 16;
            app.S_Lines.S_EndPenLines.EndLine.FontWeight = 'bold';
            app.S_Lines.S_EndPenLines.EndBWTLine = xline(app.axes_BottomWaterTemp, app.EndRecord, '--k');
            app.S_Lines.S_EndPenLines.EndDepthLine = xline(app.axes_Depth, app.EndRecord, '--k');
            app.S_Lines.S_EndPenLines.EndTiltLine = xline(app.axes_Tilt, app.EndRecord, '--k');

           app.PenEndChanged=1;

           save SlugHeat22Temp
        end

        % Size changed function: figure_Main
        function figure_MainSizeChanged(app, event)
            
            % Align X axes of all visibile plots on the PENETRATION DATA
            % tab
            % ----------------------------------------------------------
            xAlign(app.DepthPlot, app.axes_Depth, app.BWPlot, app.axes_BottomWaterTemp, ...
                app.TiltPlot, app.axes_Tilt, app.TempPlot, app.axes_TempAboveBWT)

            % Align X axes of the two time shift plots on the HEAT PULSE
            % DECAY tab
            % ----------------------------------------------------------
            
            xAlignHPD(app.button_ShowAllHP, app.axes_MisfitvTimeShift, app.axes_TempvTimeShift)

        end

        % Callback function
        function cbTest1ValueChanged(app, event)
            app.axes_BottomWaterTemp.Visible = 'off';
        end

        % Button pushed function: button_DiscardSensors
        function button_DiscardSensorsPushed(app, event)
            if ~app.PulseData
                [app.A, app.B, app.C, app.D, app.E, app.F, app.kInit, app.kFunction...
                ] = DiscardSensorsNoHP(app.AllSensors, ...
                    app.kInit, app.PulseData, app.NumberOfSensors, app.SensorDistance, app.TopSensorDepth);
            else
                app.IgnoredSensors(app.IgnoredSensors==0) = [];
                app.SensorsToUse = app.AllSensors;
            end

            app.DiscardDataDialogApp = DiscardData(app, app.IgnoredSensors, ...
                app.NumberOfSensors, app.AllSensors, app.h_axTempAboveBWT, ...
                app.LogFileId, app.ProgramLogId, app.Badk, app.BadT);
            
            pause(1);
            
            %button_ProcessButtonPushed(app, event)
            
        end

        % Callback function
        function SliderValueChanged(app, event)

            %app.Iteration = app.Slider.Value;

        end

        % Callback function
        function buttongroup_PlotControls_FricSelectionChanged(app, event)
            app.TempTimePlot     = app.button_TempvsTimeFric_.Value;
            app.TempTauPlot      = app.button_TempvsTauFric_.Value;
            app.TempBullFuncPlot = app.button_TempvsBullFunFric_.Value;
            app.MisfitShiftPlot  = app.button_MisfitvsShiftFric_.Value;

            plotLayout(app.grid_FricDecayAxes, app.TempTimePlot, app.axes_TempvTime, app.TempTauPlot,...
                app.axes_TempvTau, app.TempBullFuncPlot, app.axes_TempvBullFunc, ...
                app.MisfitShiftPlot, app.axes_MisfitvTimeShift)
            
        end

        % Callback function
        function TempvsTimeCheckBoxValueChanged(app, event)
            app.TempTimePlot     = app.TempvsTimeCheckBox.Value;
            app.TempTauPlot      = app.TempvsTauCheckBox.Value;
            app.TempBullFuncPlot = app.TempvsFCheckBox.Value;
            app.MisfitShiftPlot  = app.MisfitvsTimeShiftCheckBox.Value;

            plotLayout(app.grid_FricDecayAxes, app.TempTimePlot, app.axes_TempvTime, app.TempTauPlot,...
                app.axes_TempvTau, app.TempBullFuncPlot, app.axes_TempvBullFunc, ...
                app.MisfitShiftPlot, app.axes_MisfitvTimeShift)
            
        end

        % Callback function
        function TempvsTauCheckBoxValueChanged(app, event)
           app.TempTimePlot     = app.TempvsTimeCheckBox.Value;
            app.TempTauPlot      = app.TempvsTauCheckBox.Value;
            app.TempBullFuncPlot = app.TempvsFCheckBox.Value;
            app.MisfitShiftPlot  = app.MisfitvsTimeShiftCheckBox.Value;

            plotLayout(app.grid_FricDecayAxes, app.TempTimePlot, app.axes_TempvTime, app.TempTauPlot,...
                app.axes_TempvTau, app.TempBullFuncPlot, app.axes_TempvBullFunc, ...
                app.MisfitShiftPlot, app.axes_MisfitvTimeShift)
            
        end

        % Callback function
        function TempvsFCheckBoxValueChanged(app, event)
            app.TempTimePlot     = app.TempvsTimeCheckBox.Value;
            app.TempTauPlot      = app.TempvsTauCheckBox.Value;
            app.TempBullFuncPlot = app.TempvsFCheckBox.Value;
            app.MisfitShiftPlot  = app.MisfitvsTimeShiftCheckBox.Value;

            plotLayout(app.grid_FricDecayAxes, app.TempTimePlot, app.axes_TempvTime, app.TempTauPlot,...
                app.axes_TempvTau, app.TempBullFuncPlot, app.axes_TempvBullFunc, ...
                app.MisfitShiftPlot, app.axes_MisfitvTimeShift)
        end

        % Callback function
        function MisfitvsTimeShiftCheckBoxValueChanged(app, event)
            app.TempTimePlot     = app.TempvsTimeCheckBox.Value;
            app.TempTauPlot      = app.TempvsTauCheckBox.Value;
            app.TempBullFuncPlot = app.TempvsFCheckBox.Value;
            app.MisfitShiftPlot  = app.MisfitvsTimeShiftCheckBox.Value;

            plotLayout(app.grid_FricDecayAxes, app.TempTimePlot, app.axes_TempvTime, app.TempTauPlot,...
                app.axes_TempvTau, app.TempBullFuncPlot, app.axes_TempvBullFunc, ...
                app.MisfitShiftPlot, app.axes_MisfitvTimeShift)            
        end

        % Callback function
        function buttongroup_PlotControls_HeatPulseSelectionChanged(app, event)
            app.TempTimePlotHPPlot     = app.button_TempvsTimeHP.Value;
            app.MisfitShiftHPPlot      = app.button_MisfitvsTimeShiftHP.Value;
            app.TempInvTimeHPPlot      = app.button_TempvsInvTimeHP.Value;
            app.TempTimeShiftHPPlot    = app.button_TempvTimeShiftHP.Value;
    
            plotLayout(app.grid_HPDecayAxes, app.TempTimePlotHPPlot, ...
                app.axes_CTempvCtime, app.MisfitShiftHPPlot,...
                app.axes_MisfitvTimeShift, app.TempInvTimeHPPlot, ...
                app.axes_CTempv1_CTime, ...
                app.TempTimeShiftHPPlot, app.axes_TempvTimeShift)
            
        end

        % Value changed function: checkbox_TempvDepth
        function checkbox_TempvDepthValueChanged(app, event)
            app.BullTempvDepthPlot = app.checkbox_TempvDepth.Value;
            
            ap = num2str(app.BullTempvDepthPlot);
            bp = num2str(app.TCvDepthPlot);
            cp = num2str(app.TempvCTRPlot);
            
            % Concatenate strings of values with the letter x so that this
            % string can be used in the grid layout function
            % ---------------------------------------------
            apx = strcat(ap, 'x');
            bpx = strcat(bp, 'x');
            cpx = strcat(cp, 'x');
        
            % Update grid layout. 
            % Keep temperature plot at a constant weight 5x all other plots
            % ---------------------------------------------
            app.grid_BullAnalysisAxes.ColumnWidth = {apx, bpx, cpx};

            % If this plot is OFF, must update Y axis label for TC vs.
            % Depth
            % ---------------------------------------------------------
            if app.BullTempvDepthPlot == 0
                app.axes_TCvDepth.YLabel = app.axes_TempvDepth.YLabel;
                app.axes_TCvDepth.YTickLabel = app.axes_TempvDepth.YTickLabel;
            else
                ylabel(app.axes_TCvDepth, [])
                app.axes_TCvDepth.YTickLabel = [];
                ylabel(app.axes_TempvDepth, 'Relative Depth (m)', ...
                'FontWeight','bold', 'FontSize',16);

            end
            
        end

        % Value changed function: checkbox_TCvDepth
        function checkbox_TCvDepthValueChanged(app, event)
            app.TCvDepthPlot = app.checkbox_TCvDepth.Value;

            ap = num2str(app.BullTempvDepthPlot);
            bp = num2str(app.TCvDepthPlot);
            cp = num2str(app.TempvCTRPlot);
            
            % Concatenate strings of values with the letter x so that this
            % string can be used in the grid layout function
            % ---------------------------------------------
            apx = strcat(ap, 'x');
            bpx = strcat(bp, 'x');
            cpx = strcat(cp, 'x');
        
            % Update grid layout. 
            % Keep temperature plot at a constant weight 5x all other plots
            % ---------------------------------------------
            app.grid_BullAnalysisAxes.ColumnWidth = {apx, bpx, cpx};
            
        end

        % Value changed function: checkbox_TempvCTR
        function checkbox_TempvCTRValueChanged(app, event)
            app.TempvCTRPlot = app.checkbox_TempvCTR.Value;

            ap = num2str(app.BullTempvDepthPlot);
            bp = num2str(app.TCvDepthPlot);
            cp = num2str(app.TempvCTRPlot);
            
            % Concatenate strings of values with the letter x so that this
            % string can be used in the grid layout function
            % ---------------------------------------------
            apx = strcat(ap, 'x');
            bpx = strcat(bp, 'x');
            cpx = strcat(cp, 'x');
        
            % Update grid layout. 
            % Keep temperature plot at a constant weight 5x all other plots
            % ---------------------------------------------
            app.grid_BullAnalysisAxes.ColumnWidth = {apx, bpx, cpx};
            
        end

        % Callback function
        function AddNewMenuSelected(app, event)
%         Save penetration to another tab
%         -----------------------------------
            Menu2 = uimenu(app.menu_Penetration);
            Menu2.Text = app.PenFileName(1:end-4);
            Menu2.MenuSelectedFcn = createCallbackFcn(app, @Menu2Selected);

            %function Menu2Selected(~, src, 

            
%         Reset penetration information and axes
%         ------------------------------------------
            Reset(app.axes_TempAboveBWT, app.axes_BottomWaterTemp, ...
            app.axes_Depth, app.axes_Tilt,  app.axes_TempvTime, app.axes_TempvTau, ...
            app.axes_TempvBullFunc, app.axes_TimeShift, app.axes_CTempvCtime, ...
            app.axes_CTempv1_CTime, app.axes_MisfitvTimeShift, app.axes_TempvTimeShift, ...
            app.axes_TempvDepth, app.axes_TCvDepth, app.axes_TempvCTR, ...
            app.label_gradient, app.label_depthoftop, app.label_averagek, app.label_heatflow, app.label_shift, ...
            app.checkbox_BottomWaterPlot, app.checkbox_DepthPlot, ...
            app.checkbox_TiltPlot, app.S_MATFile, app.NumberOfSensors, app.ProgramLogId, app.Trial)
        end

        % Callback function
        function dropdown_TrialValueChanged(app, event)
            %value = app.dropdown_Trial.Value;
%
            %load([app.PenFileName(1:end-4) '_Trial_' app.Trial], 'app.panel_TempvDepth', 'app.panel_TCvDepth', 'app.panel_TempvCTR')
        end

        % Button pushed function: NextIterationButton
        function NextIterationButtonPushed(app, event)
            
            % Reset all fricitonal and heat pulse decay axes for new
            % iteration plots
            % ---------------------------------------------------
            delete(app.axes_TimeShift.Children)
            delete(app.axes_TempvTau.Children)
            delete(app.axes_TempvBullFunc.Children)
            delete(app.axes_TempvTime.Children)

            delete(app.axes_TempvTimeShift.Children)
            delete(app.axes_MisfitvTimeShift.Children)
            delete(app.axes_CTempv1_CTime.Children)
            delete(app.axes_CTempvCtime.Children)

            % Resume iterative processig while loop until max iteration
            % number
            % ---------------------------
            uiresume(app.figure_Main)
            drawnow;
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            % Manually notify app that convergence criteria has been met 
            % ----------------------------------------------------------
            app.Converged = 1;

            % Resume iterative processig while loop until max iteration
            % number
            % -------------------------------------------------------
            uiresume(app.figure_Main)
        end

        % Button pushed function: button_saveresults
        function button_saveresultsPushed(app, event)
             HFResultsSummary = app.table_HFSummary;
            
             % Save the results from heat flow summary table along with
             % column names to a .mat file and .xlsx file
             save('HFResultsSummaryTable', 'HFResultsSummary');
             HeatFlowResults = [app.table_HFSummary.ColumnName'; HFResultsSummary.Data];
             writematrix(HeatFlowResults, 'HFResultsSummary.xlsx');
             
             % Inform user that the two files were saved in the current
             % directory
             uialert(app.figure_Main, ['Heat flow results table saved as MAT file (HFResultsSummary.mat)' ... 
             ' and as EXCEL file (HFResultsSummary.xlsx) in your current path (' app.CurrentPath ')'], 'SAVED', 'Icon','success')

        end

        % Button pushed function: button_clearresults
        function button_clearresultsPushed(app, event)
            app.table_HFSummary.Data = [];
        end

        % Callback function
        function button_ShowAllFricValueChanged(app, event)

            app.ShowAllFricPlots = app.button_ShowAllFric.Value;
            app.TempTimePlot     = app.button_TempvsTimeFric_.Value;
            app.TempTauPlot      = app.button_TempvsTauFric_.Value;
            app.TempBullFuncPlot = app.button_TempvsBullFunFric_.Value;
            app.MisfitShiftPlot  = app.button_MisfitvsShiftFric_.Value;

            if app.ShowAllFricPlots == 1
                app.TempTimePlot = 1;
                app.TempTauPlot = 1; 
                app.TempBullFuncPlot = 1;
                app.MisfitShiftHPPlot = 1;
            end

    
            plotLayout(app.grid_FricDecayAxes, app.TempTimePlot, app.axes_TempvTime, app.TempTauPlot,...
                app.axes_TempvTau, app.TempBullFuncPlot, app.axes_TempvBullFunc, ...
                app.MisfitShiftPlot, app.axes_MisfitvTimeShift)
        end

        % Selection changed function: buttongroup_PlotControls_Fric
        function buttongroup_PlotControls_FricSelectionChanged2(app, event)

            app.TempTimePlot     = app.button_TempvsTimeFric.Value;
            app.TempTauPlot      = app.button_TempvsTauFric.Value;
            app.TempBullFuncPlot = app.button_TempvsBullFunFric.Value;
            app.MisfitShiftPlot  = app.button_MisfitvsShiftFric.Value;

            plotLayout(app.grid_FricDecayAxes, app.TempTimePlot, app.axes_TempvTime, app.TempTauPlot,...
                app.axes_TempvTau, app.TempBullFuncPlot, app.axes_TempvBullFunc, ...
                app.MisfitShiftPlot, app.axes_MisfitvTimeShift)
        end

        % Selection changed function: buttongroup_PlotControls_HeatPulse
        function buttongroup_PlotControls_HeatPulseSelectionChanged2(app, event)
            app.TempTimePlotHPPlot     = app.button_TempvsTimeHP.Value;
            app.MisfitShiftHPPlot      = app.button_MisfitvsTimeShiftHP.Value;
            app.TempInvTimeHPPlot      = app.button_TempvsInvTimeHP.Value;
            app.TempTimeShiftHPPlot    = app.button_TempvTimeShiftHP.Value;
    
            plotLayout(app.grid_HPDecayAxes, app.TempTimePlotHPPlot, ...
                app.axes_CTempvCtime, app.MisfitShiftHPPlot,...
                app.axes_MisfitvTimeShift, app.TempInvTimeHPPlot, ...
                app.axes_CTempv1_CTime, ...
                app.TempTimeShiftHPPlot, app.axes_TempvTimeShift)
            
        end

        % Callback function
        function buttongroup_ScatterSelectionChanged(app, event)
            app.HFScatter     = app.checkbox_HFPlot.Value;
            app.SigmaHFScatter = app.checkbox_SigmaPlot.Value;

            plotLayout(app.grid_HeatFlowRegAxes, app.HFScatter, ...
                app.axes_HeatFlow, app.SigmaHFScatter,...
                app.axes_Sigma)
            
        end

        % Value changed function: checkbox_HFPlot
        function checkbox_HFPlotValueChanged(app, event)
            app.HFPlot = app.checkbox_HFPlot.Value;

            ap = num2str(app.HFPlot);
            bp = num2str(app.SigmaPlot);
            
            % Concatenate strings of values with the letter x so that this
            % string can be used in the grid layout function
            % ---------------------------------------------
            apx = strcat(ap, 'x');
            bpx = strcat(bp, 'x');
        
            % Update grid layout. 
            % Keep temperature plot at a constant weight 5x all other plots
            % ---------------------------------------------
            app.grid_HeatFlowRegAxes.RowHeight = {apx, bpx};

            if app.HFPlot == 0
                app.HFLine.Visible = 'off';
                app.ScatterLine.Visible = 'off';
            elseif app.HFPlot == 1
                app.HFLine.Visible = 'on';
                app.ScatterLine.Visible = 'on';
            end

            plotLayout(app.grid_HeatFlowRegAxes, app.HFPlot, app.axes_HeatFlow, ...
                app.SigmaPlot, app.axes_Sigma)
            
        end

        % Value changed function: checkbox_SigmaPlot
        function checkbox_SigmaPlotValueChanged(app, event)
            app.SigmaPlot = app.checkbox_SigmaPlot.Value;

            ap = num2str(app.HFPlot);
            bp = num2str(app.SigmaPlot);
            
            % Concatenate strings of values with the letter x so that this
            % string can be used in the grid layout function
            % ---------------------------------------------
            apx = strcat(ap, 'x');
            bpx = strcat(bp, 'x');
        
            % Update grid layout. 
            % Keep temperature plot at a constant weight 5x all other plots
            % ---------------------------------------------
            app.grid_HeatFlowRegAxes.RowHeight = {apx, bpx};
            
            if app.SigmaPlot == 0
                app.SigmaHFLine.Visible = 'off';
                app.SigmaScatterLine.Visible = 'off';
            elseif app.SigmaPlot == 1
                app.SigmaHFLine.Visible = 'on';
                app.SigmaScatterLine.Visible = 'on';
            end

            plotLayout(app.grid_HeatFlowRegAxes, app.HFPlot, app.axes_HeatFlow, ...
                app.SigmaPlot, app.axes_Sigma)            
            
        end

        % Button pushed function: button_OpenControlsPanel
        function button_OpenControlsPanelButtonPushed(app, event)
            app.grid_Main.ColumnWidth = {'1x', '0x', '8x'};

            % Align X axes of all visibile plots
            % ------------------------------------
            xAlign(app.DepthPlot, app.axes_Depth, app.BWPlot, app.axes_BottomWaterTemp, ...
                app.TiltPlot, app.axes_Tilt, app.TempPlot, app.axes_TempAboveBWT)

            % Align X axes of the two time shift plots on the HEAT PULSE
            % DECAY tab
            % ----------------------------------------------------------
            xAlignHPD(app.button_ShowAllHP, app.axes_MisfitvTimeShift, app.axes_TempvTimeShift)

        end

        % Value changed function: switch_PauseBetweenIter
        function switch_PauseBetweenIterValueChanged(app, event)
            switch app.switch_PauseBetweenIter.Value
                case 'On'
                app.Pause = 1;
                case 'Off'
                app.Pause = 2;
            end
            
        end

        % Selection change function: tabgroup_Main
        function tabgroup_MainSelectionChanged(app, event)
            selectedTab = app.tabgroup_Main.SelectedTab;
            if selectedTab == app.tab_PenetrationData
               app.panel_PlotControls_Pen.Visible = 'on';
               app.panel_PlotControls_Fric.Visible = 'off';     
               app.panel_PlotControls_HeatPulse.Visible = 'off';     
               app.panel_PlotControls_HeatFlow.Visible = 'off';     
               app.panel_PlotControls_Scatter.Visible = 'off';
               app.panel_PlotControls_SensAn.Visible = 'off';
            elseif selectedTab == app.tab_FricitonalDecayReduction
               app.panel_PlotControls_Fric.Visible = 'on';
               app.panel_PlotControls_Pen.Visible = 'off';
               app.panel_PlotControls_HeatPulse.Visible = 'off';
               app.panel_PlotControls_HeatFlow.Visible = 'off';
               app.panel_PlotControls_Scatter.Visible = 'off';
               app.panel_PlotControls_SensAn.Visible = 'off';
            elseif selectedTab == app.tab_HeatPulseDecayReduction
               app.panel_PlotControls_HeatPulse.Visible = 'on';
               app.panel_PlotControls_Pen.Visible = 'off';
               app.panel_PlotControls_Fric.Visible = 'off';
               app.panel_PlotControls_HeatFlow.Visible = 'off';
               app.panel_PlotControls_Scatter.Visible = 'off';
               app.panel_PlotControls_SensAn.Visible = 'off';
            elseif selectedTab == app.tab_HeatFlowAnalysis
               app.panel_PlotControls_HeatFlow.Visible = 'on';
               app.panel_PlotControls_HeatPulse.Visible = 'off';
               app.panel_PlotControls_Pen.Visible = 'off';
               app.panel_PlotControls_Fric.Visible = 'off';
               app.panel_PlotControls_Scatter.Visible = 'off';
               app.panel_PlotControls_SensAn.Visible = 'off';
            elseif selectedTab == app.tab_HeatFlowRegressionAnalysis
               app.panel_PlotControls_Scatter.Visible = 'on';
               app.panel_PlotControls_HeatFlow.Visible = 'off';
               app.panel_PlotControls_HeatPulse.Visible = 'off';
               app.panel_PlotControls_Pen.Visible = 'off';
               app.panel_PlotControls_Fric.Visible = 'off';
               app.panel_PlotControls_SensAn.Visible = 'off';
            elseif selectedTab == app.tab_SensitivityAnalysis
               app.panel_PlotControls_SensAn.Visible = 'on';
               app.panel_PlotControls_Scatter.Visible = 'off';
               app.panel_PlotControls_HeatFlow.Visible = 'off';
               app.panel_PlotControls_HeatPulse.Visible = 'off';
               app.panel_PlotControls_Pen.Visible = 'off';
               app.panel_PlotControls_Fric.Visible = 'off';
            end


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Reset trial 
            % -----------
            app.Trial = 0;
            app.label_trial.Text = '';
            app.label_Iteration.Text = '';

            % Enable pause between iteration if first trial (iterations are
            % not done after first trial)
            % ------------------------------------------------------------------
            if app.Trial == 0
                app.switch_PauseBetweenIter.Enable = 'on';
                app.NextIterationButton.Enable = 'on';
                app.EndButton.Enable = 'on';
            else
                app.NextIterationButton.Enable = 'off';
                app.EndButton.Enable = 'off';
            end
            
                
           % CLear processing plots
           % -------------------------

            % Frictional Decay %
            delete(app.axes_TempvTime.Children)
            delete(app.axes_TempvTau.Children)
            delete(app.axes_TempvBullFunc.Children)
            delete(app.axes_TimeShift.Children)

            app.grid_FricDecayAxes.RowHeight = {'1x', '1x'};
            app.grid_FricDecayAxes.ColumnWidth = {'1x', '1x'};

            % Heat Pulse Decay %
            delete(app.axes_CTempvCtime.Children)
            delete(app.axes_CTempv1_CTime.Children)
            delete(app.axes_MisfitvTimeShift.Children)
            delete(app.axes_TempvTimeShift.Children)

            app.grid_HPDecayAxes.RowHeight = {'1x', '1x'};
            app.grid_HPDecayAxes.ColumnWidth = {'1x', '1x'};

                % Heat Flow Analysis axes
                % ----------------------
                cla(app.axes_TempvDepth, 'reset')
                cla(app.axes_TCvDepth, 'reset')
                cla(app.axes_TempvCTR, 'reset')
                app.label_gradient.Text = '';
                app.label_depthoftop.Text = '';
                app.label_averagek.Text = '';
                app.label_heatflow.Text = ''; 
                app.label_shift.Text = '';

                % Heat Flow Regression
                % ----------------------
                cla(app.axes_HeatFlow, 'reset')
                cla(app.axes_Sigma, 'reset')

          % Reset all plots for senstivity analysis
          % -----------------------------------------
          delete(app.axes_SA_TCvDepth.Children)
          delete(app.axes_SA_TempvCTR.Children)
          delete(app.axes_SA_SDvIter.Children)
          
        % 
        end

        % Button pushed function: RemoveSensorsButton
        function RemoveSensorsButtonPushed(app, event)
            % Define bad sensors
            % ----------------------
            for i=app.NumberOfSensors:-1:1
                if app.PenCheckboxes{i}.Value == 0 
                    app.IgnoredSensors(i) = i;
                end
            end

            app.IgnoredSensors(app.IgnoredSensors==0) = [];
            app.IgnoredSensors = unique(app.IgnoredSensors);
           
            
            % Warn user before removing data
            % ------------------------------------
            confirmation = uiconfirm(app.figure_Main, ['Recordings from all unchecked ' ...
                'sensors will be discarded and REMOVED from ' ...
                'data.' newline newline ...
                'Sensors discarded ' newline int2str(app.IgnoredSensors)], ...
                'Confirm sensor discard', 'Icon', 'warning');
            switch confirmation
            case 'OK'
            
                % Update LOG file to indicate which 
                % sensors are used and which are ignored
                % ----------------------------------------
                if isempty(app.IgnoredSensors) 
                    PrintStatus(app.LogFileId,'All the sensors are used in processing!   ',2);
                else
                    PrintStatus(app.LogFileId,['These sensors are IGNORED in processing: ' ...
                            int2str(app.IgnoredSensors)],2);
                end
                
	            PrintStatus(app.ProgramLogId, ['-- Removing bad data from sensors to be ignored ' ...
                    'in processing'],2);
            case 'Cancel'
                return
            
            end

            pause(1)
            
            app.Reprocess = 1;      % when this is 1, it tells the program that 
                                    % the full calculations of eq. temp and 
                                    % therm. con. need to be fully re-done, 
                                    % with every iteration
            app.SensorsRemoved = 1; % when this is 1, sensors were removed 
                                    % BEFORE any processing occurred 
                                    % (calculations for eq. temp and 
                                    % thermal conductivity were NOT made 
                                    % for these sensors.)
            
            app.SensorsToUse = setdiff(app.SensorsToUse, app.IgnoredSensors);
            %app.NumberOfSensors = length(app.SensorsToUse);
            %SensorsAndBWSensor = [app.SensorsToUse app.OrigNumberOfSensors+1];
            %app.AllSensorsRawData = app.AllSensorsRawData(:, SensorsAndBWSensor);
            %app.BottomWaterRawData = app.BottomWaterRawData(:, SensorsAndBWSensor);


            % Update calibration sensor
            % ------------------------
            app.UseWaterSensor = app.checkbox_UseWS.Value;
            
            [app.BottomWaterTemp, ...
            app.WaterSensorTemp, ...
            app.AllSensorsTemp ...
            ] = TempCorrection(...
            app.BottomWaterRawData, ...
            app.AllSensorsRawData, ...
            app.WaterThermistor, ...
            app.WaterSensorRawData, ...
            app.WaterCorrectionType, app.Offset, ...
            app.PenetrationRecord, ...
            app.AllRecords, ...
            app.EqmStartRecord, ...
            app.EqmEndRecord, app.LogFileId, app.ProgramLogId, ...
            app.UseWaterSensor, ...
            app.NumberOfSensors, app.SensorsToUse, ...
            app.SensorsRemoved, ...
            app.figure_Main);

            if ~app.WaterThermistor
                app.checkbox_UseWS.Enable = 'off';
            end

            %app.PenCheckboxes{app.IgnoredSensors}.Enable = 'off';
            %app.FricCheckboxes{app.IgnoredSensors}.Enable = 'off';
            %app.HPCheckboxes{app.IgnoredSensors}.Enable = 'off';


        end

        % Value changed function: checkbox_UseWS
        function checkbox_UseWSValueChanged(app, event)
            app.UseWaterSensor = app.checkbox_UseWS.Value;
            [app.BottomWaterTemp, ...
            app.WaterSensorTemp, ...
            app.AllSensorsTemp ...
            ] = TempCorrection(...
            app.BottomWaterRawData, ...
            app.AllSensorsRawData, ...
            app.WaterThermistor, ...
            app.WaterSensorRawData, ...
            app.WaterCorrectionType, app.Offset, ...
            app.PenetrationRecord, ...
            app.AllRecords, ...
            app.EqmStartRecord, ...
            app.EqmEndRecord, app.LogFileId, app.ProgramLogId, ...
            app.UseWaterSensor, ...
            app.NumberOfSensors, ...
            app.SensorsToUse, ...
            app.SensorsRemoved, ...
            app.figure_Main);

            if ~app.WaterThermistor
                app.UseWaterSensor.Enable = 'off';
            end

        end

        % Value changed function: checkbox_UseHP
        function checkbox_UseHPValueChanged(app, event)
        app.Reprocess = 1;
        app.UseHP = app.checkbox_UseHP.Value;
            
        if app.UseHP == 0
            app.NoInitialize = 1;
        end
        end

        % Callback function
        function PenetrationNotesTextAreaValueChanged(app, event)
            app.PenNotes = app.PenetrationNotesTextArea.Value;
        end

        % Button pushed function: button_SensAn
        function button_SensAnButtonPushed(app, event)
        
        app.tabgroup_Main.SelectedTab = app.tab_SensitivityAnalysis;

        % Reset all plots for senstivity analysis
        % -----------------------------------------
        delete(app.axes_SA_TCvDepth.Children)
        delete(app.axes_SA_TempvCTR.Children)
        delete(app.axes_SA_SDvIter.Children)

        app.label_standev.Text = ' ';
        app.label_currentiteration.Text = ' ';
        app.label_currentheatflow.Text = ' ';
        app.label_initheatflow.Text = ' ';
        app.label_heatflowrange.Text = ' ';
        app.label_meanheatflow.Text = ' ';
        app.label_kDistribution.Text = ' ';
        app.label_thermconbounds.Text = ' ';
        app.label_meandev.Text = ' ';
        app.label_minthickness.Text = ' ';

        % Initialize sensitivity analysis
        % --------------------------------
        app.SensAnalysisRunning = 1;
        VarDist = [];

        % If no in situ therm con measurements, label axis with information on
        % the distribution of random therm con values
        if app.PulseData
            app.label_MeanDevTitle.Visible = 'off';
            app.label_meandev.Visible = 'off';
        end
            

        % Define what sensors to use
        % --------------------------
   
          GoodT = setxor(app.SensorsUsedForBullardFit,app.BadT);
          Goodk = setxor(app.SensorsUsedForBullardFit,app.Badk);
          
          TToUse = intersect(GoodT,app.SensorsUsedForBullardFit);    % SensorsToUse for T.
          app.kToUse = intersect(Goodk,app.SensorsUsedForBullardFit);    % SensorsToUse for k.
        
          % Print status on LOG file for running sensitivity analysis
        % ----------------------------------------------------------

        % PrintStatus(app.LogFileId ....
        
        % Set up input paramters for analysis
        % -------------------------------------

            app.SetUpSensDialogApp = SetUpSensAnalysis(app, ...
                app.NumberOfSensors,...
                app.SensorsToUse, ...
                app.kToUse, ...
                app.Currentk, ...
                app.PulseData, ...
                app.MaxSAIterations, ...
                app.Sigmak0, ...
                app.kMin, ...
                app.kMax, ...
                app.MinThickness, ...
                app.UseFrictional, ...
                app.kAnisotropy);
            
            % Update labels if the user chose a different distribution of
            % therm con values
            %       NORMAL : app.kDistribution == 1
            %       GAMMA : app.kDistribution == 2
            %       NONE: app.kDistribution == 3
            
            % Wait to execute rest of code until auxillary app is deleted
            waitfor(app.SetUpSensDialogApp)

            if app.kDistribution == 1
                app.label_kDistribution.Text = 'NORMAL distribution of thermal conductivity values';
            elseif app.kDistribution == 2
                app.label_kDistribution.Text = 'GAMMA distribution of thermal conductivity values';
            elseif app.kDistribution == 3 && app.kAnisotropy == 1
                app.label_kDistribution.Text = 'NO distribution of thermal conductivity values. Only measured values used';
            elseif app.kDistribution == 3 && app.kAnisotropy ~= 1
                app.label_kDistribution.Text = 'NO distribution of thermal conductivity values. Only measured values used, with anisotropy accounted for';
            end
        
        if app.RunAnalysis ~= 1
            return
        end

        % Run function for sensitivity analysis
        % -------------------------------------
        loading = uiprogressdlg(app.figure_Main, "Cancelable","on", ...
               "Message",'Running sensitivity analysis', 'Value', 0.2);


            % =======================================    
            % Run analysis
            % ======================================= 
            
            loading.Message = 'Beginning sensitivity analysis...';
            
            app.tabgroup_Main.SelectedTab = app.tab_SensitivityAnalysis;
           
                        SensitivityAnalysis(app.NumberOfSensors,...
                            app.kToUse, ...
                            VarDist,...
                            app.MaxSAIterations, ...
                            app.Sigmak0, ...
                            app.kMin, ...
                            app.kMax, ...
                            app.MinThickness, ...
                            app.kAnisotropy, ...
                            app.SensorsToUse, ...
                            app.IgnoredSensors, ...
                            app.SensorDistance, ...
                            app.BadT, ...
                            app.Badk, ...
                            app.ShiftedCTR, ...
                            app.CTR, ...
                            app.ShiftedRelativeDepths, ...
                            app.Slope, ...
                            app.Currentk, ...
                            app.MinimumFricEqTemp, ...
                            app.h_axTempAboveBWT, ...
                            app.SensorsUsedForBullardFit, ...
                            app.HeatFlow, ...
                            app.axes_SA_SDvIter, ...
                            app.axes_SA_TCvDepth, ...
                            app.axes_SA_TempvCTR, ...
                            app.label_heatflowrange, ...
                            app.label_meanheatflow, ...
                            app.label_currentheatflow, ...
                            app.label_initheatflow, ...
                            app.label_standev, ...
                            app.label_currentiteration, ...
                            app.label_meandev, ...
                            app.label_minthickness, ...
                            app.label_thermconbounds, ...
                            app.label_ThermConBoundsTitle, ...
                            app.PulseData, ...
                            loading, ...
                            app.table_ErrorUncertaintySummary, ...
                            app.ErrData, ...
                            app.kDistribution, ...
                            app.r, ...
                            app.UseFrictional, ...
                            app.figure_Main, ...
                            app.FricTime, ...
                            app.FricTemp, ...
                            app.FrictionalDelays, ...
                            app.FricMaxStep, ...
                            app.TimeInc, ...
                            app.HyndmanCoeffs, ...
                            app.SensorRadius, ...
                            app.FricTauMin, ...
                            app.FricTauMax);


        % Close sensitivity analysis
        % --------------------------------
        app.SensAnalysisRunning = 0;

       app.button_saveAllResults.Enable = 'on';
       app.button_saveErrRestults.Enable = 'on';
       app.button_clearErrResults.Enable = 'on';

        end

        % Button pushed function: button_saveErrRestults
        function button_saveErrRestultsButtonPushed(app, event)
             ErrResultsSummary = app.table_ErrorUncertaintySummary;
            
             % Save the results from heat flow summary table along with
             % column names to a .mat file and .xlsx file
             save('ErrResultsSummaryTable', 'ErrResultsSummary');
             ErrorUncertaintyResults = [ErrResultsSummary.ColumnName'; ErrResultsSummary.Data];
             writematrix(ErrorUncertaintyResults, 'ErrResultsSummary.xlsx');
             
             % Inform user that the two files were saved in the current
             % directory
             uialert(app.figure_Main, ['Error and uncertainty analysis results table saved as MAT file (ErrResultsSummary.mat)' ... 
             ' and as EXCEL file (ErrResultsSummary.xlsx) ' newline newline ...
             'Saved in your current path (' app.CurrentPath ')'], 'SAVED', 'Icon','success')
        end

        % Button pushed function: button_clearErrResults
        function button_clearErrResultsButtonPushed(app, event)
            app.table_ErrorUncertaintySummary.Data = [];
        end

        % Button pushed function: button_saveAllResults
        function button_saveAllResultsPushed(app, event)
            AllResultsColumnNames = [app.table_HFSummary.ColumnName(1:end-1) app.table_ErrorUncertaintySummary.ColumnName(5:end)];
            AllResultsData = [app.HFData app.ErrData];
           

             AllResults = [AllResultsColumnNames; AllResultsData];
            
             % Save the results from heat flow summary table along with
             % column names to a .mat file and .xlsx file
             save('AllDataResultsSummaryTable', 'AllResults');

             writematrix(AllResults, 'AllResultsSummary.xlsx');
             
             % Inform user that the two files were saved in the current
             % directory
             uialert(app.figure_Main, ['Error and uncertainty analysis results table saved as MAT file (AllResultsSummary.mat)' ... 
             ' and as EXCEL file (AllResultsSummary.xlsx) ' newline newline ...
             'Saved in your current path (' app.CurrentPath ')'], 'SAVED', 'Icon','success')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure_Main = uifigure('Visible', 'off');
            app.figure_Main.Name = 'MATLAB App';
            app.figure_Main.SizeChangedFcn = createCallbackFcn(app, @figure_MainSizeChanged, true);
            app.grid_Main = uigridlayout(app.figure_Main);
            app.panel_ControlWindow = uipanel(app.grid_Main);
            app.grid_ControlWindow = uigridlayout(app.panel_ControlWindow);
            app.panel_DataControls = uipanel(app.grid_ControlWindow);
            app.panel_DataControls.Tooltip = {'Control timing of penetration events'};
            app.panel_DataControls.Title = 'Data Controls';
            app.grid_DataControls = uigridlayout(app.panel_DataControls);
            app.PenetrtionStartLabel = uilabel(app.grid_DataControls);
            app.PenetrtionStartLabel.Text = 'Penetration Start:';
            app.edit_PenStart = uieditfield(app.grid_DataControls, 'numeric');
            app.edit_PenStart.ValueChangedFcn = createCallbackFcn(app, @edit_PenStartValueChanged, true);
            app.HeatPulseLabel = uilabel(app.grid_DataControls);
            app.HeatPulseLabel.Text = 'Heat Pulse:';
            app.edit_HP = uieditfield(app.grid_DataControls, 'numeric');
            app.edit_HP.ValueChangedFcn = createCallbackFcn(app, @edit_HPValueChanged, true);
            app.PenetrationEndLabel = uilabel(app.grid_DataControls);
            app.PenetrationEndLabel.Text = 'Penetration End:';
            app.edit_PenEnd = uieditfield(app.grid_DataControls, 'numeric');
            app.edit_PenEnd.ValueChangedFcn = createCallbackFcn(app, @edit_PenEndValueChanged, true);
            app.panel_OutputFiles = uipanel(app.grid_ControlWindow);
            app.panel_OutputFiles.Tooltip = {'Output files generated when penetration data is processed'};
            app.panel_OutputFiles.Title = 'Output Files';
            app.grid_OutputFiles = uigridlayout(app.panel_OutputFiles);
            app.label_resultsfilename = uilabel(app.grid_OutputFiles);
            app.label_resultsfilename.Text = '';
            app.label_LogFile = uilabel(app.grid_OutputFiles);
            app.label_LogFile.Text = 'Log File:';
            app.label_logfilename = uilabel(app.grid_OutputFiles);
            app.label_logfilename.Text = '';
            app.label_ResFile = uilabel(app.grid_OutputFiles);
            app.label_ResFile.Text = 'Results File:';
            app.label_resfilename = uilabel(app.grid_OutputFiles);
            app.label_resfilename.Text = '';
            app.panel_Parameters = uipanel(app.grid_ControlWindow);
            app.panel_Parameters.Title = 'Parameters ';
            app.grid_Parameters = uigridlayout(app.panel_Parameters);
            app.label_ParFile = uilabel(app.grid_Parameters);
            app.label_ParFile.Text = 'Default Parameters File:';
            app.button_SetlParams = uibutton(app.grid_Parameters, 'push');
            app.button_SetlParams.ButtonPushedFcn = createCallbackFcn(app, @button_SetlParamsPushed, true);
            app.button_SetlParams.Text = 'Manually set parameters';
            app.label_parfilename = uilabel(app.grid_Parameters);
            app.label_parfilename.Text = '';
            app.label_CalFile = uilabel(app.grid_Parameters);
            app.label_CalFile.Text = 'Default Calibration File:';
            app.label_calfilename = uilabel(app.grid_Parameters);
            app.label_calfilename.Text = '';
            app.panel_PenetrationInfo = uipanel(app.grid_ControlWindow);
            app.panel_PenetrationInfo.Tooltip = {'Penetration file and TAP (temperature and pressure) file loaded in'};
            app.panel_PenetrationInfo.Title = 'Penetration Data';
            app.grid_PenetrationInfo = uigridlayout(app.panel_PenetrationInfo);
            app.label_PenFile = uilabel(app.grid_PenetrationInfo);
            app.label_PenFile.Text = 'Penetration File:';
            app.label_penfilename = uilabel(app.grid_PenetrationInfo);
            app.label_penfilename.Text = '';
            app.label_TapFile = uilabel(app.grid_PenetrationInfo);
            app.label_TapFile.Text = 'TAP File:';
            app.label_tapfilename = uilabel(app.grid_PenetrationInfo);
            app.label_tapfilename.Text = '';
            app.button_LoadPen = uibutton(app.grid_PenetrationInfo, 'push');
            app.button_LoadPen.ButtonPushedFcn = createCallbackFcn(app, @button_LoadPenPushed, true);
            app.button_LoadPen.Text = 'Load Penetration';
            app.panel_header = uipanel(app.grid_ControlWindow);
            app.grid_header = uigridlayout(app.panel_header);
            app.button_ControlWindowExit = uibutton(app.grid_header, 'push');
            app.button_ControlWindowExit.ButtonPushedFcn = createCallbackFcn(app, @button_ControlWindowExitButtonPushed, true);
            app.button_ControlWindowExit.Icon = fullfile(pathToMLAPP, 'Images', 'x .png');
            app.button_ControlWindowExit.Tooltip = {'Hide controls window'};
            app.button_ControlWindowExit.Text = '';
            app.button_Restart = uibutton(app.grid_header, 'push');
            app.button_Restart.ButtonPushedFcn = createCallbackFcn(app, @button_RestartPushed, true);
            app.button_Restart.Text = 'Restart';
            app.panel_Logos = uipanel(app.grid_header);
            app.grid_Logos = uigridlayout(app.panel_Logos);
            app.image_UCSCLogo = uiimage(app.grid_Logos);
            app.image_UCSCLogo.ImageSource = fullfile(pathToMLAPP, 'Images', 'OSU.jpg');
            app.image_WHLogo = uiimage(app.grid_Logos);
            app.image_WHLogo.ImageSource = fullfile(pathToMLAPP, 'Images', 'woodsholelogo.png');
            app.image_OSULogo = uiimage(app.grid_Logos);
            app.image_OSULogo.ImageSource = fullfile(pathToMLAPP, 'Images', 'UCSC-HydrogeologyLogo.jpg');
            app.image_SlugHeat = uiimage(app.grid_header);
            app.image_SlugHeat.ImageSource = fullfile(pathToMLAPP, 'Images', 'slugheatLogo.png');
            app.panel_Commands = uipanel(app.grid_ControlWindow);
            app.panel_Commands.Title = 'Commands';
            app.grid_Commands = uigridlayout(app.panel_Commands);
            app.button_SensAn = uibutton(app.grid_Commands, 'push');
            app.button_SensAn.ButtonPushedFcn = createCallbackFcn(app, @button_SensAnButtonPushed, true);
            app.button_SensAn.Enable = 'off';
            app.button_SensAn.Tooltip = {'Coming soon....'};
            app.button_SensAn.Text = 'Run Sensitivity Analysis';
            app.button_DiscardSensors = uibutton(app.grid_Commands, 'push');
            app.button_DiscardSensors.ButtonPushedFcn = createCallbackFcn(app, @button_DiscardSensorsPushed, true);
            app.button_DiscardSensors.Enable = 'off';
            app.button_DiscardSensors.Tooltip = {'Discard data from certain sensors '};
            app.button_DiscardSensors.Text = 'Ignore Data ';
            app.EndButton = uibutton(app.grid_Commands, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Enable = 'off';
            app.EndButton.Text = 'End';
            app.NextIterationButton = uibutton(app.grid_Commands, 'push');
            app.NextIterationButton.ButtonPushedFcn = createCallbackFcn(app, @NextIterationButtonPushed, true);
            app.NextIterationButton.Enable = 'off';
            app.NextIterationButton.Text = 'Next Iteration';
            app.PauseIterationsLabel = uilabel(app.grid_Commands);
            app.PauseIterationsLabel.Enable = 'off';
            app.PauseIterationsLabel.Text = 'Pause Iterations';
            app.switch_PauseBetweenIter = uiswitch(app.grid_Commands, 'slider');
            app.switch_PauseBetweenIter.ValueChangedFcn = createCallbackFcn(app, @switch_PauseBetweenIterValueChanged, true);
            app.switch_PauseBetweenIter.Enable = 'off';
            app.ResetButton = uibutton(app.grid_Commands, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Reset penetration data and prep for re-processing'};
            app.ResetButton.Text = 'Reset ';
            app.checkbox_UseHP = uicheckbox(app.grid_Commands);
            app.checkbox_UseHP.ValueChangedFcn = createCallbackFcn(app, @checkbox_UseHPValueChanged, true);
            app.checkbox_UseHP.Text = 'Use HP reduction';
            app.checkbox_UseHP.Value = true;
            app.button_Process = uibutton(app.grid_Commands, 'push');
            app.button_Process.ButtonPushedFcn = createCallbackFcn(app, @button_ProcessButtonPushed, true);
            app.button_Process.Tooltip = {'Calculate equilibrium temperatures, thermal conductivity, and heat flow '};
            app.button_Process.Text = 'Process Data';
            app.checkbox_UseWS = uicheckbox(app.grid_Commands);
            app.checkbox_UseWS.ValueChangedFcn = createCallbackFcn(app, @checkbox_UseWSValueChanged, true);
            app.checkbox_UseWS.Text = 'Use water sensor';
            app.checkbox_UseWS.Value = true;
            app.panel_PlotControls_SensAn = uipanel(app.grid_ControlWindow);
            app.panel_PlotControls_SensAn.Title = 'Plot Controls';
            app.grid_PlotControls_Pen_2 = uigridlayout(app.panel_PlotControls_SensAn);
            app.panel_PlotControls_Scatter = uipanel(app.grid_ControlWindow);
            app.panel_PlotControls_Scatter.Title = 'Plot Controls';
            app.grid_Scatter = uigridlayout(app.panel_PlotControls_Scatter);
            app.checkbox_HFPlot = uicheckbox(app.grid_Scatter);
            app.checkbox_HFPlot.ValueChangedFcn = createCallbackFcn(app, @checkbox_HFPlotValueChanged, true);
            app.checkbox_HFPlot.Text = 'Heat Flow Scatter';
            app.checkbox_HFPlot.Value = true;
            app.checkbox_SigmaPlot = uicheckbox(app.grid_Scatter);
            app.checkbox_SigmaPlot.ValueChangedFcn = createCallbackFcn(app, @checkbox_SigmaPlotValueChanged, true);
            app.checkbox_SigmaPlot.Text = 'Sigma Heat Flow Scatter';
            app.checkbox_SigmaPlot.Value = true;
            app.panel_PlotControls_HeatFlow = uipanel(app.grid_ControlWindow);
            app.panel_PlotControls_HeatFlow.Tooltip = {'Turn display of plots ''on'' and ''off'''};
            app.panel_PlotControls_HeatFlow.Title = 'Plot Controls';
            app.grid_PlotControls_HeatFlow = uigridlayout(app.panel_PlotControls_HeatFlow);
            app.checkbox_TempvCTR = uicheckbox(app.grid_PlotControls_HeatFlow);
            app.checkbox_TempvCTR.ValueChangedFcn = createCallbackFcn(app, @checkbox_TempvCTRValueChanged, true);
            app.checkbox_TempvCTR.Text = 'Temp vs. CTR';
            app.checkbox_TempvCTR.Value = true;
            app.checkbox_TempvDepth = uicheckbox(app.grid_PlotControls_HeatFlow);
            app.checkbox_TempvDepth.ValueChangedFcn = createCallbackFcn(app, @checkbox_TempvDepthValueChanged, true);
            app.checkbox_TempvDepth.Text = 'Temp vs. Depth';
            app.checkbox_TempvDepth.Value = true;
            app.checkbox_TCvDepth = uicheckbox(app.grid_PlotControls_HeatFlow);
            app.checkbox_TCvDepth.ValueChangedFcn = createCallbackFcn(app, @checkbox_TCvDepthValueChanged, true);
            app.checkbox_TCvDepth.Text = 'TC vs. Depth';
            app.checkbox_TCvDepth.Value = true;
            app.panel_PlotControls_HeatPulse = uipanel(app.grid_ControlWindow);
            app.panel_PlotControls_HeatPulse.Title = 'Plot Controls';
            app.GridLayout4 = uigridlayout(app.panel_PlotControls_HeatPulse);
            app.buttongroup_PlotControls_HeatPulse = uibuttongroup(app.GridLayout4);
            app.buttongroup_PlotControls_HeatPulse.SelectionChangedFcn = createCallbackFcn(app, @buttongroup_PlotControls_HeatPulseSelectionChanged2, true);
            app.button_ShowAllHP = uiradiobutton(app.buttongroup_PlotControls_HeatPulse);
            app.button_ShowAllHP.Text = 'Show all plots';
            app.button_ShowAllHP.Value = true;
            app.button_TempvsTimeHP = uiradiobutton(app.buttongroup_PlotControls_HeatPulse);
            app.button_TempvsTimeHP.Text = 'Temp vs. Time (s)';
            app.button_MisfitvsTimeShiftHP = uiradiobutton(app.buttongroup_PlotControls_HeatPulse);
            app.button_MisfitvsTimeShiftHP.Text = 'Misfit vs. Time Shift';
            app.button_TempvsInvTimeHP = uiradiobutton(app.buttongroup_PlotControls_HeatPulse);
            app.button_TempvsInvTimeHP.Text = 'Temp vs. 1/Time (s-1)';
            app.button_TempvTimeShiftHP = uiradiobutton(app.buttongroup_PlotControls_HeatPulse);
            app.button_TempvTimeShiftHP.Text = 'Temp vs. Time Shift';
            app.panel_PlotControls_Fric = uipanel(app.grid_ControlWindow);
            app.panel_PlotControls_Fric.Title = 'Plot Controls ';
            app.grid_PlotControls_Fric = uigridlayout(app.panel_PlotControls_Fric);
            app.buttongroup_PlotControls_Fric = uibuttongroup(app.grid_PlotControls_Fric);
            app.buttongroup_PlotControls_Fric.SelectionChangedFcn = createCallbackFcn(app, @buttongroup_PlotControls_FricSelectionChanged2, true);
            app.button_ShowAllFric = uiradiobutton(app.buttongroup_PlotControls_Fric);
            app.button_ShowAllFric.Text = 'Show all plots';
            app.button_ShowAllFric.Value = true;
            app.button_TempvsTimeFric = uiradiobutton(app.buttongroup_PlotControls_Fric);
            app.button_TempvsTimeFric.Text = 'Temp vs. Time (s)';
            app.button_TempvsTauFric = uiradiobutton(app.buttongroup_PlotControls_Fric);
            app.button_TempvsTauFric.Text = 'Temp vs. Tau ';
            app.button_TempvsBullFunFric = uiradiobutton(app.buttongroup_PlotControls_Fric);
            app.button_TempvsBullFunFric.Text = 'Temp vs.  F(α,τ)  ';
            app.button_MisfitvsShiftFric = uiradiobutton(app.buttongroup_PlotControls_Fric);
            app.button_MisfitvsShiftFric.Text = 'Misfit vs. Time Shift ';
            app.panel_PlotControls_Pen = uipanel(app.grid_ControlWindow);
            app.panel_PlotControls_Pen.Title = 'Plot Controls';
            app.grid_PlotControls_Pen = uigridlayout(app.panel_PlotControls_Pen);
            app.checkbox_TiltPlot = uicheckbox(app.grid_PlotControls_Pen);
            app.checkbox_TiltPlot.ValueChangedFcn = createCallbackFcn(app, @checkbox_TiltPlotValueChanged, true);
            app.checkbox_TiltPlot.Text = 'Tilt';
            app.checkbox_TiltPlot.Value = true;
            app.checkbox_BottomWaterPlot = uicheckbox(app.grid_PlotControls_Pen);
            app.checkbox_BottomWaterPlot.ValueChangedFcn = createCallbackFcn(app, @checkbox_BottomWaterPlotValueChanged, true);
            app.checkbox_BottomWaterPlot.Text = 'Bottom Water';
            app.checkbox_BottomWaterPlot.Value = true;
            app.checkbox_DepthPlot = uicheckbox(app.grid_PlotControls_Pen);
            app.checkbox_DepthPlot.ValueChangedFcn = createCallbackFcn(app, @checkbox_DepthPlotValueChanged, true);
            app.checkbox_DepthPlot.Text = 'Depth';
            app.checkbox_DepthPlot.Value = true;
            app.panel_TabGroup = uipanel(app.grid_Main);
            app.grid_TabGroup = uigridlayout(app.panel_TabGroup);
            app.tabgroup_Main = uitabgroup(app.grid_TabGroup);
            app.tabgroup_Main.SelectionChangedFcn = createCallbackFcn(app, @tabgroup_MainSelectionChanged, true);
            app.tab_PenetrationData = uitab(app.tabgroup_Main);
            app.tab_PenetrationData.Title = 'Penetration Data';
            app.grid_PenetrationData = uigridlayout(app.tab_PenetrationData);
            app.panel_PenDataAxes = uipanel(app.grid_PenetrationData);
            app.grid_PenDataAxes = uigridlayout(app.panel_PenDataAxes);
            app.axes_TempAboveBWT = uiaxes(app.grid_PenDataAxes);
            ylabel(app.axes_TempAboveBWT, 'Temperature relative to bottom water (°C)')
            zlabel(app.axes_TempAboveBWT, 'Z')
            app.axes_TempAboveBWT.Tag = 'axes_tag';
            app.axes_Tilt = uiaxes(app.grid_PenDataAxes);
            ylabel(app.axes_Tilt, 'Tilt (°)')
            zlabel(app.axes_Tilt, 'Z')
            app.axes_BottomWaterTemp = uiaxes(app.grid_PenDataAxes);
            ylabel(app.axes_BottomWaterTemp, 'Bottom Water Temperature (°C)')
            app.axes_BottomWaterTemp.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.axes_Depth = uiaxes(app.grid_PenDataAxes);
            ylabel(app.axes_Depth, 'Depth (m)')
            zlabel(app.axes_Depth, 'Z')
            app.axes_Depth.YDir = 'reverse';
            app.label_RecordNumber = uilabel(app.grid_PenDataAxes);
            app.label_RecordNumber.Text = 'Record Number';
            app.panel_PD_Thermistors = uipanel(app.grid_PenetrationData);
            app.panel_PD_Thermistors.Title = 'Thermistors';
            app.RemoveSensorsButton = uibutton(app.grid_PenetrationData, 'push');
            app.RemoveSensorsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSensorsButtonPushed, true);
            app.RemoveSensorsButton.Tooltip = {'Remove data from all unchecked sensors '};
            app.RemoveSensorsButton.Text = 'Remove Sensors';
            app.tab_FricitonalDecayReduction = uitab(app.tabgroup_Main);
            app.tab_FricitonalDecayReduction.Title = 'Frictional Decay Reduction';
            app.grid_FrictionalDecayReduction = uigridlayout(app.tab_FricitonalDecayReduction);
            app.panel_FricDecayAxes = uipanel(app.grid_FrictionalDecayReduction);
            app.grid_FricDecayAxes = uigridlayout(app.panel_FricDecayAxes);
            app.axes_TempvTime = uiaxes(app.grid_FricDecayAxes);
            xlabel(app.axes_TempvTime, 'X')
            ylabel(app.axes_TempvTime, 'Y')
            zlabel(app.axes_TempvTime, 'Z')
            app.axes_TempvBullFunc = uiaxes(app.grid_FricDecayAxes);
            xlabel(app.axes_TempvBullFunc, 'X')
            ylabel(app.axes_TempvBullFunc, 'Y')
            zlabel(app.axes_TempvBullFunc, 'Z')
            app.axes_TempvTau = uiaxes(app.grid_FricDecayAxes);
            xlabel(app.axes_TempvTau, 'X')
            ylabel(app.axes_TempvTau, 'Y')
            zlabel(app.axes_TempvTau, 'Z')
            app.axes_TimeShift = uiaxes(app.grid_FricDecayAxes);
            xlabel(app.axes_TimeShift, 'X')
            ylabel(app.axes_TimeShift, 'Y')
            zlabel(app.axes_TimeShift, 'Z')
            app.panel_FD_Thermistors = uipanel(app.grid_FrictionalDecayReduction);
            app.panel_FD_Thermistors.Title = 'Thermistors';
            app.tab_HeatPulseDecayReduction = uitab(app.tabgroup_Main);
            app.tab_HeatPulseDecayReduction.Title = 'Heat Pulse Decay Reduction';
            app.grid_HeatPulseDecayReduction = uigridlayout(app.tab_HeatPulseDecayReduction);
            app.panel_HPDecayAxes = uipanel(app.grid_HeatPulseDecayReduction);
            app.grid_HPDecayAxes = uigridlayout(app.panel_HPDecayAxes);
            app.axes_CTempvCtime = uiaxes(app.grid_HPDecayAxes);
            xlabel(app.axes_CTempvCtime, 'X')
            ylabel(app.axes_CTempvCtime, 'Y')
            zlabel(app.axes_CTempvCtime, 'Z')
            app.axes_CTempv1_CTime = uiaxes(app.grid_HPDecayAxes);
            xlabel(app.axes_CTempv1_CTime, 'X')
            ylabel(app.axes_CTempv1_CTime, 'Y')
            zlabel(app.axes_CTempv1_CTime, 'Z')
            app.axes_MisfitvTimeShift = uiaxes(app.grid_HPDecayAxes);
            xlabel(app.axes_MisfitvTimeShift, 'X')
            ylabel(app.axes_MisfitvTimeShift, 'Y')
            zlabel(app.axes_MisfitvTimeShift, 'Z')
            app.axes_TempvTimeShift = uiaxes(app.grid_HPDecayAxes);
            xlabel(app.axes_TempvTimeShift, 'X')
            ylabel(app.axes_TempvTimeShift, 'Y')
            zlabel(app.axes_TempvTimeShift, 'Z')
            app.panel_HPD_Thermistors = uipanel(app.grid_HeatPulseDecayReduction);
            app.panel_HPD_Thermistors.Title = 'Thermistors';
            app.tab_HeatFlowAnalysis = uitab(app.tabgroup_Main);
            app.tab_HeatFlowAnalysis.Title = 'Heat Flow Analysis';
            app.grid_HeatFlowAnalysis = uigridlayout(app.tab_HeatFlowAnalysis);
            app.panel_BullAnalysisAxes = uipanel(app.grid_HeatFlowAnalysis);
            app.grid_BullAnalysisAxes = uigridlayout(app.panel_BullAnalysisAxes);
            app.panel_TempvDepth = uipanel(app.grid_BullAnalysisAxes);
            app.grid_TempvDepth = uigridlayout(app.panel_TempvDepth);
            app.axes_TempvDepth = uiaxes(app.grid_TempvDepth);
            xlabel(app.axes_TempvDepth, 'X')
            ylabel(app.axes_TempvDepth, 'Y')
            zlabel(app.axes_TempvDepth, 'Z')
            app.grid_GradientDepth = uigridlayout(app.grid_TempvDepth);
            app.label_gradient = uilabel(app.grid_GradientDepth);
            app.label_gradient.Text = 'gradient';
            app.label_DepthofTopTitle = uilabel(app.grid_GradientDepth);
            app.label_DepthofTopTitle.Text = 'Depth of Top Sensor:';
            app.label_depthoftop = uilabel(app.grid_GradientDepth);
            app.label_depthoftop.Text = 'depth of top sensor';
            app.label_GradientTitle = uilabel(app.grid_GradientDepth);
            app.label_GradientTitle.Text = 'Gradient:';
            app.panel_TCvDepth = uipanel(app.grid_BullAnalysisAxes);
            app.grid_TCvDepth = uigridlayout(app.panel_TCvDepth);
            app.axes_TCvDepth = uiaxes(app.grid_TCvDepth);
            xlabel(app.axes_TCvDepth, 'X')
            ylabel(app.axes_TCvDepth, 'Y')
            zlabel(app.axes_TCvDepth, 'Z')
            app.axes_TCvDepth.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.panel_averagek = uipanel(app.grid_TCvDepth);
            app.grid_Averagek = uigridlayout(app.panel_averagek);
            app.label_AveragekTitle = uilabel(app.grid_Averagek);
            app.label_AveragekTitle.Text = 'Average Thermal Conductivity:';
            app.label_averagek = uilabel(app.grid_Averagek);
            app.label_averagek.Text = 'average k';
            app.panel_CTRvTemp = uipanel(app.grid_BullAnalysisAxes);
            app.grid_CTRvTemp = uigridlayout(app.panel_CTRvTemp);
            app.axes_TempvCTR = uiaxes(app.grid_CTRvTemp);
            xlabel(app.axes_TempvCTR, 'X')
            ylabel(app.axes_TempvCTR, 'Y')
            zlabel(app.axes_TempvCTR, 'Z')
            app.panel_HF_Shift = uipanel(app.grid_CTRvTemp);
            app.grid_HFShfit = uigridlayout(app.panel_HF_Shift);
            app.label_HeatFlowTitle = uilabel(app.grid_HFShfit);
            app.label_HeatFlowTitle.Text = 'Heat Flow:';
            app.label_ShiftTitle = uilabel(app.grid_HFShfit);
            app.label_ShiftTitle.Text = 'Shift:';
            app.label_shift = uilabel(app.grid_HFShfit);
            app.label_shift.Text = 'shift';
            app.label_heatflow = uilabel(app.grid_HFShfit);
            app.label_heatflow.Text = 'heat flow';
            app.panel_BA_Thermistors = uipanel(app.grid_HeatFlowAnalysis);
            app.panel_BA_Thermistors.Title = 'Thermistors';
            app.tab_HeatFlowRegressionAnalysis = uitab(app.tabgroup_Main);
            app.tab_HeatFlowRegressionAnalysis.Title = 'Heat Flow Regression Analysis';
            app.grid_HeatFlowRegressionAnalysis = uigridlayout(app.tab_HeatFlowRegressionAnalysis);
            app.panel_HeatFlowRegAxes = uipanel(app.grid_HeatFlowRegressionAnalysis);
            app.grid_HeatFlowRegAxes = uigridlayout(app.panel_HeatFlowRegAxes);
            app.axes_Sigma = uiaxes(app.grid_HeatFlowRegAxes);
            xlabel(app.axes_Sigma, 'X')
            ylabel(app.axes_Sigma, 'Y')
            zlabel(app.axes_Sigma, 'Z')
            app.axes_Sigma.XDir = 'reverse';
            app.axes_HeatFlow = uiaxes(app.grid_HeatFlowRegAxes);
            xlabel(app.axes_HeatFlow, 'X')
            ylabel(app.axes_HeatFlow, 'Y')
            zlabel(app.axes_HeatFlow, 'Z')
            app.axes_HeatFlow.XDir = 'reverse';
            app.panel_HF_Thermistors = uipanel(app.grid_HeatFlowRegressionAnalysis);
            app.panel_HF_Thermistors.Title = 'Thermistors';
            app.tab_SensitivityAnalysis = uitab(app.tabgroup_Main);
            app.tab_SensitivityAnalysis.Title = 'Sensitivity Analysis';
            app.grid_SensitivityAnalysis = uigridlayout(app.tab_SensitivityAnalysis);
            app.panel_SensAnalysisAxes = uipanel(app.grid_SensitivityAnalysis);
            app.grid_SensAnalysisAxes = uigridlayout(app.panel_SensAnalysisAxes);
            app.panel_SA_TempvCTR = uipanel(app.grid_SensAnalysisAxes);
            app.grid_SA_TempvCTR = uigridlayout(app.panel_SA_TempvCTR);
            app.axes_SA_TCvDepth = uiaxes(app.grid_SA_TempvCTR);
            xlabel(app.axes_SA_TCvDepth, 'X')
            ylabel(app.axes_SA_TCvDepth, 'Y')
            zlabel(app.axes_SA_TCvDepth, 'Z')
            app.grid_distributions = uigridlayout(app.grid_SA_TempvCTR);
            app.label_MinThickTitle = uilabel(app.grid_distributions);
            app.label_MinThickTitle.Text = 'Minimum Layer Thickness:';
            app.label_MeanDevTitle = uilabel(app.grid_distributions);
            app.label_MeanDevTitle.Text = 'Standard Deviation:';
            app.label_minthickness = uilabel(app.grid_distributions);
            app.label_minthickness.Text = '';
            app.label_meandev = uilabel(app.grid_distributions);
            app.label_meandev.Text = '';
            app.label_ThermConBoundsTitle = uilabel(app.grid_distributions);
            app.label_ThermConBoundsTitle.Text = 'Thermal Conductivity Value Bounds:';
            app.label_thermconbounds = uilabel(app.grid_distributions);
            app.label_thermconbounds.Text = '[min max]';
            app.label_kDistribution = uilabel(app.grid_distributions);
            app.label_kDistribution.Text = 'NORMAL Distribution of Thermal Conductivity Values';
            app.panel_SA_SDvIter = uipanel(app.grid_SensAnalysisAxes);
            app.grid_SA_SDvIter = uigridlayout(app.panel_SA_SDvIter);
            app.axes_SA_TempvCTR = uiaxes(app.grid_SA_SDvIter);
            xlabel(app.axes_SA_TempvCTR, 'X')
            ylabel(app.axes_SA_TempvCTR, 'Y')
            zlabel(app.axes_SA_TempvCTR, 'Z')
            app.panel_heatflow_SA = uipanel(app.grid_SA_SDvIter);
            app.grid_heatflow_SA = uigridlayout(app.panel_heatflow_SA);
            app.label_MeanHFTitle = uilabel(app.grid_heatflow_SA);
            app.label_MeanHFTitle.Text = 'Mean Heat Flow:';
            app.label_meanheatflow = uilabel(app.grid_heatflow_SA);
            app.label_meanheatflow.Text = 'mean heat flow';
            app.label_HFRangeTitle = uilabel(app.grid_heatflow_SA);
            app.label_HFRangeTitle.Text = 'Total Heat Flow Range:';
            app.label_heatflowrange = uilabel(app.grid_heatflow_SA);
            app.label_heatflowrange.Text = 'total heat flow range';
            app.label_InitHeatFlowTitle = uilabel(app.grid_heatflow_SA);
            app.label_InitHeatFlowTitle.Text = 'Initial Heat Flow:';
            app.label_initheatflow = uilabel(app.grid_heatflow_SA);
            app.label_initheatflow.Text = 'initial heat flow';
            app.label_CurrentHFTitle = uilabel(app.grid_heatflow_SA);
            app.label_CurrentHFTitle.Text = 'Current Heat Flow:';
            app.label_currentheatflow = uilabel(app.grid_heatflow_SA);
            app.label_currentheatflow.Text = 'current heat flow';
            app.panel_SA_TCvDepth = uipanel(app.grid_SensAnalysisAxes);
            app.grid_SA_TCvDepth = uigridlayout(app.panel_SA_TCvDepth);
            app.axes_SA_SDvIter = uiaxes(app.grid_SA_TCvDepth);
            xlabel(app.axes_SA_SDvIter, 'X')
            ylabel(app.axes_SA_SDvIter, 'Y')
            zlabel(app.axes_SA_SDvIter, 'Z')
            app.Panel_6 = uipanel(app.grid_SA_TCvDepth);
            app.grid_iterations = uigridlayout(app.Panel_6);
            app.label_CurrentIterationTitle = uilabel(app.grid_iterations);
            app.label_CurrentIterationTitle.Text = 'Current Iterations:';
            app.label_currentiteration = uilabel(app.grid_iterations);
            app.label_currentiteration.Text = 'current iteration';
            app.label_StanDevTitle = uilabel(app.grid_iterations);
            app.label_StanDevTitle.Text = 'Standard Deviation:';
            app.label_standev = uilabel(app.grid_iterations);
            app.label_standev.Text = 'standard deviation';
            app.panel_SA_Thermistors = uipanel(app.grid_SensitivityAnalysis);
            app.panel_SA_Thermistors.Title = 'Thermistors';
            app.tab_IterativevsInversionMethods = uitab(app.tabgroup_Main);
            app.tab_IterativevsInversionMethods.Title = 'Iterative vs. Inversion Methods';
            app.panel_ItervInvMethods = uipanel(app.tab_IterativevsInversionMethods);
            app.axes_HeatPulseTesting = uiaxes(app.panel_ItervInvMethods);
            title(app.axes_HeatPulseTesting, 'Title')
            xlabel(app.axes_HeatPulseTesting, 'X')
            ylabel(app.axes_HeatPulseTesting, 'Y')
            zlabel(app.axes_HeatPulseTesting, 'Z')
            app.tab_HFSummary = uitab(app.tabgroup_Main);
            app.tab_HFSummary.Title = 'Results Summary';
            app.panel_HFSummary = uipanel(app.tab_HFSummary);
            app.panel_HFSummary.Title = 'Heat Flow Results:';
            app.grid_HFSummary = uigridlayout(app.panel_HFSummary);
            app.table_HFSummary = uitable(app.grid_HFSummary);
            app.table_HFSummary.ColumnName = {'Cruise'; 'Station'; 'Penetration'; 'Trial Number'; 'Iterations'; 'Heat Pulse Decay Reduction?'; 'Total Number of Sensors'; 'Sensors Ignored'; 'Tilt (°)'; 'Therm. Gradient (°C/m)'; 'Av. Therm. Conductivity (W/m°C)'; 'Heat Flow (mW/m^2)'; 'Notes'};
            app.table_HFSummary.RowName = {};
            app.table_HFSummary.ColumnEditable = true;
            app.button_clearresults = uibutton(app.grid_HFSummary, 'push');
            app.button_clearresults.ButtonPushedFcn = createCallbackFcn(app, @button_clearresultsPushed, true);
            app.button_clearresults.Text = 'CLEAR';
            app.button_saveresults = uibutton(app.grid_HFSummary, 'push');
            app.button_saveresults.ButtonPushedFcn = createCallbackFcn(app, @button_saveresultsPushed, true);
            app.button_saveresults.Text = 'SAVE';
            app.panel_ErrorUncertaintyAnalysisTable = uipanel(app.grid_HFSummary);
            app.panel_ErrorUncertaintyAnalysisTable.Title = 'Error and Uncertainty Results:';
            app.table_ErrorUncertaintySummary = uitable(app.panel_ErrorUncertaintyAnalysisTable);
            app.table_ErrorUncertaintySummary.ColumnName = {'Cruise'; 'Station'; 'Penetration'; 'Trial Number'; 'Iterations'; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.table_ErrorUncertaintySummary.RowName = {};
            app.table_ErrorUncertaintySummary.ColumnEditable = true;
            app.button_saveErrRestults = uibutton(app.grid_HFSummary, 'push');
            app.button_saveErrRestults.ButtonPushedFcn = createCallbackFcn(app, @button_saveErrRestultsButtonPushed, true);
            app.button_saveErrRestults.Text = 'SAVE';
            app.button_clearErrResults = uibutton(app.grid_HFSummary, 'push');
            app.button_clearErrResults.ButtonPushedFcn = createCallbackFcn(app, @button_clearErrResultsButtonPushed, true);
            app.button_clearErrResults.Text = 'CLEAR';
            app.button_saveAllResults = uibutton(app.grid_HFSummary, 'push');
            app.button_saveAllResults.ButtonPushedFcn = createCallbackFcn(app, @button_saveAllResultsPushed, true);
            app.button_saveAllResults.Text = 'Save all results';
            app.Tab = uitab(app.tabgroup_Main);
            app.Tab.Title = 'Tab';
            app.panel_ProcessingInfoLabels = uipanel(app.grid_TabGroup);
            app.grid_ProcessingInfoLabels = uigridlayout(app.panel_ProcessingInfoLabels);
            app.edit_StationTitle = uilabel(app.grid_ProcessingInfoLabels);
            app.edit_StationTitle.Text = 'Station: ';
            app.edit_PenetrationTitle = uilabel(app.grid_ProcessingInfoLabels);
            app.edit_PenetrationTitle.Text = 'Penetration:';
            app.edit_LatitudeTitle = uilabel(app.grid_ProcessingInfoLabels);
            app.edit_LatitudeTitle.Text = 'Latitude:';
            app.edit_LongitudeTitle = uilabel(app.grid_ProcessingInfoLabels);
            app.edit_LongitudeTitle.Text = 'Longitude:';
            app.edit_ProcessingTimeTitle = uilabel(app.grid_ProcessingInfoLabels);
            app.edit_ProcessingTimeTitle.Text = 'Processing Time:';
            app.edit_CruiseTitle = uilabel(app.grid_ProcessingInfoLabels);
            app.edit_CruiseTitle.Text = 'Cuise:';
            app.label_Cruise = uilabel(app.grid_ProcessingInfoLabels);
            app.label_Cruise.Text = 'cruise name';
            app.label_Station = uilabel(app.grid_ProcessingInfoLabels);
            app.label_Station.Text = 'station name';
            app.label_Penetration = uilabel(app.grid_ProcessingInfoLabels);
            app.label_Penetration.Text = 'penetration name';
            app.label_Latitude = uilabel(app.grid_ProcessingInfoLabels);
            app.label_Latitude.Text = 'latitude';
            app.label_Longitude = uilabel(app.grid_ProcessingInfoLabels);
            app.label_Longitude.Text = 'longitude';
            app.label_ProcessingTime = uilabel(app.grid_ProcessingInfoLabels);
            app.label_ProcessingTime.Text = 'start of processing';
            app.panel_TopLabels = uipanel(app.grid_TabGroup);
            app.grid_TopLabels = uigridlayout(app.panel_TopLabels);
            app.label_SlugHeat = uilabel(app.grid_TopLabels);
            app.label_SlugHeat.Text = 'SlugHeat';
            app.label_CurrentPath = uilabel(app.grid_TopLabels);
            app.label_CurrentPath.Text = 'Current Path:';
            app.label_currentpathfull = uilabel(app.grid_TopLabels);
            app.label_currentpathfull.Text = '';
            app.label_IterationTitle = uilabel(app.grid_TopLabels);
            app.label_IterationTitle.Text = 'Iterations:';
            app.label_Iteration = uilabel(app.grid_TopLabels);
            app.label_Iteration.Text = '';
            app.label_TrialTitle = uilabel(app.grid_TopLabels);
            app.label_TrialTitle.Text = 'Trial:';
            app.label_trial = uilabel(app.grid_TopLabels);
            app.label_trial.Text = '';
            app.Panel_4 = uipanel(app.grid_TabGroup);
            app.button_OpenControlsPanel = uibutton(app.grid_Main, 'push');
            app.button_OpenControlsPanel.ButtonPushedFcn = createCallbackFcn(app, @button_OpenControlsPanelButtonPushed, true);
            app.button_OpenControlsPanel.Icon = fullfile(pathToMLAPP, 'Images', 'arrow.png');
            app.button_OpenControlsPanel.Text = '';
            % Show the figure after all components are created
            app.figure_Main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SlugHeat22_working_220926

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure_Main)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure_Main)
        end
    end
end