classdef SetUpSensAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure_SensAnalSetUp       matlab.ui.Figure
        grid_histograms            matlab.ui.container.GridLayout
        panel_setupsens            matlab.ui.container.Panel
        button_runsensanalysis     matlab.ui.control.Button
        label_NOTusingHP_3         matlab.ui.control.Label
        label_NOTusingHP_2         matlab.ui.control.Label
        edit_kanisotropy           matlab.ui.control.NumericEditField
        HorizontalthermalconductivityanisotropyLabel  matlab.ui.control.Label
        edit_minthickness          matlab.ui.control.NumericEditField
        MinimumlayerthicknessEditFieldLabel  matlab.ui.control.Label
        switch_usefric             matlab.ui.control.Switch
        UseFrictionaldecayreductionLabel  matlab.ui.control.Label
        edit_maxk                  matlab.ui.control.NumericEditField
        MaximumthermalconductivityEditFieldLabel  matlab.ui.control.Label
        edit_mink                  matlab.ui.control.NumericEditField
        MinimumthermalconductivityEditFieldLabel  matlab.ui.control.Label
        edit_standdev              matlab.ui.control.NumericEditField
        StandarddeviationinthermalconductivityLabel  matlab.ui.control.Label
        edit_numIter               matlab.ui.control.NumericEditField
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        line3                      matlab.ui.control.Label
        line                       matlab.ui.control.Label
        panel_runanalysis          matlab.ui.container.Panel
        panel_distributionchoices  matlab.ui.container.ButtonGroup
        ButtonGroup                matlab.ui.container.ButtonGroup
        dropdown_sensor            matlab.ui.control.DropDown
        SensorDropDownLabel        matlab.ui.control.Label
        button_none                matlab.ui.control.RadioButton
        button_gamma               matlab.ui.control.RadioButton
        button_normal              matlab.ui.control.RadioButton
        grid_histogramsFull        matlab.ui.container.GridLayout
        panel_gamma                matlab.ui.container.Panel
        grid_gamma                 matlab.ui.container.GridLayout
        axes_gamma                 matlab.ui.control.UIAxes
        panel_normal               matlab.ui.container.Panel
        grid_normal                matlab.ui.container.GridLayout
        axes_normal                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp 
        RunAnalysis

        kDistribution
        r
        RandomNormal
        RandomGamma
        histnormal
        histgamma
        CurrentSensor

        SensorsToUse
        k0
        Sigmak0

        kMin
        kMax

        Bins
        MaxSAIterations

        HistogramPlotGrid
        panels_histaxes
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, NumberOfSensors, SensorsToUse, kToUse, k0, PulseData, MaxSAIterations, Sigmak0, kMin, kMax, MinThickness, UseFrictional, kAnisotropy)
            app.CallingApp = mainapp;
            app.figure_SensAnalSetUp.WindowStyle = 'modal';
            movegui(app.figure_SensAnalSetUp, "center");
            app.SensorsToUse = SensorsToUse;
            app.k0 = k0;
            app.Sigmak0 = Sigmak0;

            app.kMin = kMin;
            app.kMax = kMax;

            app.MaxSAIterations = MaxSAIterations;

            %SensorsToUse = num2str(SensorsToUse);
            
            if UseFrictional
                app.switch_usefric.Value = 'On';
            else
                app.switch_usefric.Value = 'Off';
            end
            
            app.edit_minthickness.Value = MinThickness;
            app.edit_maxk.Value = app.kMax;
            app.edit_mink.Value = app.kMin;
            app.edit_standdev.Value = app.Sigmak0;
            app.edit_numIter.Value = app.MaxSAIterations;
            app.edit_kanisotropy.Value = kAnisotropy;

            % Set this paramater to 0 until the button 'Run Sensitivity
            % Analysis' is pressed. This way if the user x's out of this
            % app, the sensitivity analysis will not run.
            app.RunAnalysis = 0;


            % Initiate sensor dropdwn
            app.dropdown_sensor.Items = string(1:NumberOfSensors);
            app.dropdown_sensor.Value = '1';
            app.CurrentSensor = str2double(app.dropdown_sensor.Value);

            % Set limits to axes
            app.axes_normal.XLim = [app.kMin-0.05*app.kMin app.kMax+0.05*app.kMax];
            app.axes_gamma.XLim = [app.kMin-0.05*app.kMin Inf];
            

            % Generate a distribution of random conductivities for each sensor
            %   mu: initial k calculated in heat pulse analysis
            %   sigma: standard deviation decided by user
            %   truncation: min and max k decided by user 
            % ------------------------------------------------------------

            %Default bin number for histograms
            app.Bins = 25;
            
            % Initialize arrays
            pd = NaN*zeros(1, NumberOfSensors);
            app.r = NaN*zeros(app.MaxSAIterations, NumberOfSensors);
            app.RandomNormal = NaN*zeros(app.MaxSAIterations, NumberOfSensors);
            app.RandomGamma = NaN*zeros(app.MaxSAIterations, NumberOfSensors);
            

            SigmakFactors = ones(1,length(app.SensorsToUse));
            app.Sigmak0 = app.Sigmak0.*SigmakFactors;
           

        % ==============================================================
        % Thermal conductivity: Plot all distributions and calculate 
        % random distributions 
        % ==============================================================

                % Disable k anisotropy until user chooses to use no
                % distribution of k
                app.edit_kanisotropy.Enable = 'off';

                % Highlight axes with NORMAL distribution
                app.panel_normal.BorderType = 'line';
                app.panel_gamma.BorderType = 'none';
                app.panel_normal.BackgroundColor = [0.47,0.67,0.19]; % make background green
                app.panel_gamma.BackgroundColor = [.94 .94 .94]; % make background white
                app.grid_normal.BackgroundColor = [0.47,0.67,0.19]; % make background green
                app.grid_gamma.BackgroundColor = [.94 .94 .94]; % make background white
            
                for i=app.SensorsToUse
                    % Make a normal distribution with the mean 1 (a typical therm con value) and std of 0.1
                    pd = makedist('normal', app.k0(i), app.Sigmak0(i));
                    
                    % Truncate the normal distributiom between the minimum and maximum 
                    % k values determined by the user
                    pd = truncate(pd, app.kMin, app.kMax);
            
                    % Generate random numbers based on this distribution. This is a 100x11
                    % matrix for 100 iterations x number of sensors used
                    app.RandomNormal(:,i) = random(pd,app.MaxSAIterations,1);
    
                % Plot histograms separtely
                % -------------------------
                % Construct a histogram using 100 bins with a normal distribution fit. This is only a histogram of the 1ST column of r (a random therm con value for each iteration for the 1st sensor)
                app.histnormal{i} = histfit(app.axes_normal, app.RandomNormal(:,i),app.Bins,'normal');
                if i > 1
                   app.histnormal{i}(1).Visible = 'off';
                   app.histnormal{i}(2).Visible = 'off';
                end
                hold(app.axes_normal, 'on')
                %histnormal{i}.Visible = 0;

                xline(app.axes_normal, app.kMax, '--k')
                xline(app.axes_normal, app.kMin, '--k')

                end 

                for i=app.SensorsToUse
                    % Make a gamma distribution with the mean as the
                    % initial thermal conductivty value and std determined
                    % by user
                    
                        %Calculate shape parameter (a) and scale parameter
                        %(b)

                        a = ((app.k0(i))^2) / (app.Sigmak0(i))^2;
                        b = ((app.Sigmak0(i))^2) / (app.k0(i)) ;
                        

                    pd = makedist('gamma', 'a', a, 'b', b);
                    
                    % Truncate the left side of the gamma distributiom
                    % between the minimum k value determined by user
                    pd = truncate(pd, app.kMin, app.kMax);
            
                    % Generate random numbers based on this distribution. This is a 100x11
                    % matrix for 100 iterations x number of sensors used
                    app.RandomGamma(:,i) = random(pd,app.MaxSAIterations,1);
               
    
                % Plot histograms separtely
                % -------------------------              
                app.histgamma{i} = histfit(app.axes_gamma,app.RandomGamma(:,i),app.Bins,'gamma');
                if i > 1
                   app.histgamma{i}(1).Visible = 'off';
                   app.histgamma{i}(2).Visible = 'off';
                end
                hold(app.axes_gamma, 'on')

                xline(app.axes_gamma, app.kMin, '--k')

                end 

               % Initially set to normal distribution of k
                app.kDistribution = 1;
                app.r = app.RandomNormal;

        end

        % Button pushed function: button_runsensanalysis
        function button_runsensanalysisPushed(app, event)
            switch app.switch_usefric.Value
                case 'On'
                    UseFrictional = 1;
                case 'Off'
                    UseFrictional = 0;
            end

            app.RunAnalysis = 1;
            
            setSens(app.CallingApp, ...
                app.edit_numIter.Value, ...
                app.edit_standdev.Value, ...
                app.edit_mink.Value, ...
                app.edit_maxk.Value, ...
                app.edit_minthickness.Value, ...
                UseFrictional, ...
                app.edit_kanisotropy.Value, ...
                app.kDistribution, ...
                app.r, ...
                app.RunAnalysis)

            delete(app)
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)

            if app.button_normal.Value == 1

                % Highlight axes with NORMAL distribution
                app.panel_normal.BorderType = 'line';
                app.panel_gamma.BorderType = 'none';
                app.panel_normal.BackgroundColor = [0.47,0.67,0.19]; % make background green
                app.panel_gamma.BackgroundColor = [.94 .94 .94]; % make background white
                app.grid_normal.BackgroundColor = [0.47,0.67,0.19]; % make background green
                app.grid_gamma.BackgroundColor = [.94 .94 .94]; % make background white

                % Default for normal distribution is kMax from .par file
                app.edit_maxk.Value = app.kMax;

                % Disable k anisotropy and enable all other edit boxes 
                % while a distribution is chosen
                app.edit_kanisotropy.Enable = 'off';
                app.edit_maxk.Enable = "on";
                app.edit_mink.Enable = 'on';
                app.edit_standdev.Enable = 'on';
                app.switch_usefric.Enable = 'on';

                app.kDistribution = 1;

                app.r = app.RandomNormal;

            end

            if app.button_gamma.Value == 1

                % Highlight axes with GAMMA distribution
                app.panel_gamma.BorderType = 'line';
                app.panel_normal.BorderType = 'none';
                app.panel_gamma.BackgroundColor = [0.47,0.67,0.19]; % make background green
                app.panel_normal.BackgroundColor = [.94 .94 .94]; % make background white
                app.grid_gamma.BackgroundColor = [0.47,0.67,0.19]; % make background green
                app.grid_normal.BackgroundColor = [.94 .94 .94]; % make background white
                
                % Default for gamma distribution is no max k
                app.edit_maxk.Value = Inf;

                % Disable k anisotropy and enable all other edit boxes 
                % while a distribution is chosen
                app.edit_kanisotropy.Enable = 'off';
                app.edit_maxk.Enable = "on";
                app.edit_mink.Enable = 'on';
                app.edit_standdev.Enable = 'on';
                app.switch_usefric.Enable = 'on';

                app.kDistribution = 2;

                app.r = app.RandomGamma;

            end

            if app.button_none.Value == 1

                app.panel_gamma.BorderType = 'none';
                app.panel_normal.BorderType = 'none';
                app.panel_gamma.BackgroundColor = [.94 .94 .94]; % make background white
                app.panel_normal.BackgroundColor = [.94 .94 .94]; % make background white
                app.grid_gamma.BackgroundColor = [.94 .94 .94]; % make background white
                app.grid_normal.BackgroundColor = [.94 .94 .94]; % make background white

                app.edit_maxk.Enable = "off";
                app.edit_mink.Enable = 'off';
                app.edit_standdev.Enable = 'off';
                app.switch_usefric.Enable = 'off';
                app.edit_kanisotropy.Enable = 'on';
                
                app.kDistribution = 3;

            end

        end

        % Value changed function: dropdown_sensor
        function dropdown_sensorValueChanged(app, event)

            sens = str2double(app.dropdown_sensor.Value);

            % Turn off old sensor plot
            app.histnormal{app.CurrentSensor}(1).Visible = 'off';
            app.histnormal{app.CurrentSensor}(2).Visible = 'off';

            app.histgamma{app.CurrentSensor}(1).Visible = 'off';
            app.histgamma{app.CurrentSensor}(2).Visible = 'off';

            % Turn on new sensor plot
            app.histnormal{sens}(1).Visible = 'on';
            app.histnormal{sens}(2).Visible = 'on';
            
            app.histgamma{sens}(1).Visible = 'on';
            app.histgamma{sens}(2).Visible = 'on';

            app.CurrentSensor = sens;
        end

        % Value changed function: edit_maxk
        function edit_maxkValueChanged(app, event)
        app.kMax = app.edit_maxk.Value;


        % Set limits to axes
        app.axes_normal.XLim = [app.kMin-0.05*app.kMin app.kMax+0.05*app.kMax];
        app.axes_gamma.XLim = [app.kMin-0.05*app.kMin app.kMax+0.05*app.kMax];
                      
        % Delete children of all histograms
        delete(app.axes_normal.Children) 
        delete(app.axes_gamma.Children) 

        % ==============================================================
        % Thermal conductivity: Plot all distributions and calculate 
        % random distributions 
        % ==============================================================
            
                for i=app.SensorsToUse
                    % Make a normal distribution with the mean 1 (a typical therm con value) and std of 0.1
                    pd = makedist('normal', app.k0(i), app.Sigmak0(i));
                    
                    % Truncate the normal distributiom between the minimum and maximum 
                    % k values determined by the user
                    pd = truncate(pd, app.kMin, app.kMax);
            
                    % Generate random numbers based on this distribution. This is a 100x11
                    % matrix for 100 iterations x number of sensors used
                    app.RandomNormal(:,i) = random(pd,app.MaxSAIterations,1);
    
                % Plot histograms separtely
                % -------------------------
                % Construct a histogram using 100 bins with a normal distribution fit. This is only a histogram of the 1ST column of r (a random therm con value for each iteration for the 1st sensor)
                app.histnormal{i} = histfit(app.axes_normal, app.RandomNormal(:,i),app.Bins,'normal');
                if i > 1
                   app.histnormal{i}(1).Visible = 'off';
                   app.histnormal{i}(2).Visible = 'off';
                end
                hold(app.axes_normal, 'on')
                %histnormal{i}.Visible = 0;

                xline(app.axes_normal, app.kMax, '--k')
                xline(app.axes_normal, app.kMin, '--k')

                end 

                for i=app.SensorsToUse
                    % Make a gamma distribution with the mean as the
                    % initial thermal conductivty value and std determined
                    % by user
                    
                        %Calculate shape parameter (a) and scale parameter
                        %(b)

                        a = ((app.k0(i))^2) / (app.Sigmak0(i))^2;
                        b = ((app.Sigmak0(i))^2) / (app.k0(i)) ;
                        

                    pd = makedist('gamma', 'a', a, 'b', b);
                    
                    % Truncate the left side of the gamma distributiom
                    % between the minimum k value determined by user
                    pd = truncate(pd, app.kMin, app.kMax);
            
                    % Generate random numbers based on this distribution. This is a 100x11
                    % matrix for 100 iterations x number of sensors used
                    app.RandomGamma(:,i) = random(pd,app.MaxSAIterations,1);
               
    
                % Plot histograms separtely
                % -------------------------              
                app.histgamma{i} = histfit(app.axes_gamma,app.RandomGamma(:,i),app.Bins,'gamma');
                if i > 1
                   app.histgamma{i}(1).Visible = 'off';
                   app.histgamma{i}(2).Visible = 'off';
                end
                hold(app.axes_gamma, 'on')

                xline(app.axes_gamma, app.kMax, '--k')
                xline(app.axes_gamma, app.kMin, '--k')


                end 

               % Initially set to normal distribution of k
                app.kDistribution = 1;
                app.r = app.RandomNormal;
        end

        % Value changed function: edit_mink
        function edit_minkValueChanged(app, event)
        app.kMin = app.edit_mink.Value;


        % Delete children of all histograms
        delete(app.axes_normal.Children) 
        delete(app.axes_gamma.Children) 

        % Set limits to axes
        app.axes_normal.XLim = [app.kMin-0.05*app.kMin app.kMax+0.05*app.kMax];
        app.axes_gamma.XLim = [app.kMin-0.05*app.kMin app.kMax+0.05*app.kMax];
        
        % ==============================================================
        % Thermal conductivity: Plot all distributions and calculate 
        % random distributions 
        % ==============================================================
            
                for i=app.SensorsToUse
                    % Make a normal distribution with the mean 1 (a typical therm con value) and std of 0.1
                    pd = makedist('normal', app.k0(i), app.Sigmak0(i));
                    
                    % Truncate the normal distributiom between the minimum and maximum 
                    % k values determined by the user
                    pd = truncate(pd, app.kMin, app.kMax);
            
                    % Generate random numbers based on this distribution. This is a 100x11
                    % matrix for 100 iterations x number of sensors used
                    app.RandomNormal(:,i) = random(pd,app.MaxSAIterations,1);
    
                % Plot histograms separtely
                % -------------------------
                % Construct a histogram using 100 bins with a normal distribution fit. This is only a histogram of the 1ST column of r (a random therm con value for each iteration for the 1st sensor)
                app.histnormal{i} = histfit(app.axes_normal, app.RandomNormal(:,i),app.Bins,'normal');
                if i > 1
                   app.histnormal{i}(1).Visible = 'off';
                   app.histnormal{i}(2).Visible = 'off';
                end
                hold(app.axes_normal, 'on')

                xline(app.axes_normal, app.kMax, '--k')
                xline(app.axes_normal, app.kMin, '--k')

                end 

                for i=app.SensorsToUse
                    % Make a gamma distribution with the mean as the
                    % initial thermal conductivty value and std determined
                    % by user
                    
                        %Calculate shape parameter (a) and scale parameter
                        %(b)

                        a = ((app.k0(i))^2) / (app.Sigmak0(i))^2;
                        b = ((app.Sigmak0(i))^2) / (app.k0(i)) ;
                        

                    pd = makedist('gamma', 'a', a, 'b', b);
                    
                    % Truncate the left side of the gamma distributiom
                    % between the minimum k value determined by user
                    pd = truncate(pd, app.kMin, app.kMax);
            
                    % Generate random numbers based on this distribution. This is a 100x11
                    % matrix for 100 iterations x number of sensors used
                    app.RandomGamma(:,i) = random(pd,app.MaxSAIterations,1);
               
    
                % Plot histograms separtely
                % -------------------------              
                app.histgamma{i} = histfit(app.axes_gamma,app.RandomGamma(:,i),app.Bins,'gamma');
                if i > 1
                   app.histgamma{i}(1).Visible = 'off';
                   app.histgamma{i}(2).Visible = 'off';
                end
                hold(app.axes_gamma, 'on')
                
                xline(app.axes_gamma, app.kMax, '--k')
                xline(app.axes_gamma, app.kMin, '--k')

                end 

               % Initially set to normal distribution of k
                app.kDistribution = 1;
                app.r = app.RandomNormal;
        end

        % Value changed function: edit_standdev
        function edit_standdevValueChanged(app, event)
        app.Sigmak0 = app.edit_standdev.Value;

        SigmakFactors = ones(1,length(app.SensorsToUse));
        app.Sigmak0 = app.Sigmak0.*SigmakFactors;


        % Delete children of all histograms
        delete(app.axes_normal.Children) 
        delete(app.axes_gamma.Children)         
            
        % ==============================================================
        % Thermal conductivity: Plot all distributions and calculate 
        % random distributions 
        % ==============================================================
            
                for i=app.SensorsToUse
                    % Make a normal distribution with the mean 1 (a typical therm con value) and std of 0.1
                    pd = makedist('normal', app.k0(i), app.Sigmak0(i));
                    
                    % Truncate the normal distributiom between the minimum and maximum 
                    % k values determined by the user
                    pd = truncate(pd, app.kMin, app.kMax);
            
                    % Generate random numbers based on this distribution. This is a 100x11
                    % matrix for 100 iterations x number of sensors used
                    app.RandomNormal(:,i) = random(pd,app.MaxSAIterations,1);
    
                % Plot histograms separtely
                % -------------------------
                % Construct a histogram using 100 bins with a normal distribution fit. This is only a histogram of the 1ST column of r (a random therm con value for each iteration for the 1st sensor)
                app.histnormal{i} = histfit(app.axes_normal, app.RandomNormal(:,i),app.Bins,'normal');
                if i > 1
                   app.histnormal{i}(1).Visible = 'off';
                   app.histnormal{i}(2).Visible = 'off';
                end
                hold(app.axes_normal, 'on')

                xline(app.axes_normal, app.kMax, '--k')
                xline(app.axes_normal, app.kMin, '--k')

                end 

                for i=app.SensorsToUse
                    % Make a gamma distribution with the mean as the
                    % initial thermal conductivty value and std determined
                    % by user
                    
                        %Calculate shape parameter (a) and scale parameter
                        %(b)

                        a = ((app.k0(i))^2) / (app.Sigmak0(i))^2;
                        b = ((app.Sigmak0(i))^2) / (app.k0(i)) ;
                        

                    pd = makedist('gamma', 'a', a, 'b', b);
                    
                    % Truncate the left side of the gamma distributiom
                    % between the minimum k value determined by user
                    pd = truncate(pd, app.kMin, app.kMax);
            
                    % Generate random numbers based on this distribution. This is a 100x11
                    % matrix for 100 iterations x number of sensors used
                    app.RandomGamma(:,i) = random(pd,app.MaxSAIterations,1);
               
    
                % Plot histograms separtely
                % -------------------------              
                app.histgamma{i} = histfit(app.axes_gamma,app.RandomGamma(:,i),app.Bins,'gamma');
                if i > 1
                   app.histgamma{i}(1).Visible = 'off';
                   app.histgamma{i}(2).Visible = 'off';
                end
                hold(app.axes_gamma, 'on')

                xline(app.axes_gamma, app.kMax, '--k')
                xline(app.axes_gamma, app.kMin, '--k')

                end 

               % Initially set to normal distribution of k
                app.kDistribution = 1;
                app.r = app.RandomNormal;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure_SensAnalSetUp = uifigure('Visible', 'off');
            app.figure_SensAnalSetUp.Name = 'MATLAB App';
            app.grid_histograms = uigridlayout(app.figure_SensAnalSetUp);
            app.grid_histogramsFull = uigridlayout(app.grid_histograms);
            app.panel_normal = uipanel(app.grid_histogramsFull);
            app.panel_normal.Title = 'NORMAL';
            app.grid_normal = uigridlayout(app.panel_normal);
            app.axes_normal = uiaxes(app.grid_normal);
            xlabel(app.axes_normal, 'Thermal Conductivity (W/m°C)')
            ylabel(app.axes_normal, 'Count')
            zlabel(app.axes_normal, 'Z')
            app.panel_gamma = uipanel(app.grid_histogramsFull);
            app.panel_gamma.Title = 'GAMMA';
            app.grid_gamma = uigridlayout(app.panel_gamma);
            app.axes_gamma = uiaxes(app.grid_gamma);
            xlabel(app.axes_gamma, 'Thermal Conductivity (W/m°C)')
            ylabel(app.axes_gamma, 'Count')
            zlabel(app.axes_gamma, 'Z')
            app.panel_distributionchoices = uibuttongroup(app.grid_histograms);
            app.panel_distributionchoices.Title = 'Distributions of Thermal Conductivity Values';
            app.ButtonGroup = uibuttongroup(app.panel_distributionchoices);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.button_normal = uiradiobutton(app.ButtonGroup);
            app.button_normal.Text = 'Normal';
            app.button_normal.Value = true;
            app.button_gamma = uiradiobutton(app.ButtonGroup);
            app.button_gamma.Text = 'Gamma';
            app.button_none = uiradiobutton(app.ButtonGroup);
            app.button_none.Text = 'None';
            app.SensorDropDownLabel = uilabel(app.ButtonGroup);
            app.SensorDropDownLabel.Text = 'Sensor';
            app.dropdown_sensor = uidropdown(app.ButtonGroup);
            app.dropdown_sensor.ValueChangedFcn = createCallbackFcn(app, @dropdown_sensorValueChanged, true);
            app.panel_runanalysis = uipanel(app.grid_histograms);
            app.panel_setupsens = uipanel(app.grid_histograms);
            app.panel_setupsens.Title = 'Set Up Sensitivity Analysis Parameters';
            app.line = uilabel(app.panel_setupsens);
            app.line.Text = '------------------------------------------------------------------------------------';
            app.line3 = uilabel(app.panel_setupsens);
            app.line3.Text = '------------------------------------------------------------------------------------';
            app.NumberofIterationsEditFieldLabel = uilabel(app.panel_setupsens);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations:';
            app.edit_numIter = uieditfield(app.panel_setupsens, 'numeric');
            app.StandarddeviationinthermalconductivityLabel = uilabel(app.panel_setupsens);
            app.StandarddeviationinthermalconductivityLabel.Text = 'Standard deviation in thermal conductivity:';
            app.edit_standdev = uieditfield(app.panel_setupsens, 'numeric');
            app.edit_standdev.ValueChangedFcn = createCallbackFcn(app, @edit_standdevValueChanged, true);
            app.MinimumthermalconductivityEditFieldLabel = uilabel(app.panel_setupsens);
            app.MinimumthermalconductivityEditFieldLabel.Text = 'Minimum thermal conductivity:';
            app.edit_mink = uieditfield(app.panel_setupsens, 'numeric');
            app.edit_mink.ValueChangedFcn = createCallbackFcn(app, @edit_minkValueChanged, true);
            app.MaximumthermalconductivityEditFieldLabel = uilabel(app.panel_setupsens);
            app.MaximumthermalconductivityEditFieldLabel.Text = 'Maximum thermal conductivity:';
            app.edit_maxk = uieditfield(app.panel_setupsens, 'numeric');
            app.edit_maxk.ValueChangedFcn = createCallbackFcn(app, @edit_maxkValueChanged, true);
            app.UseFrictionaldecayreductionLabel = uilabel(app.panel_setupsens);
            app.UseFrictionaldecayreductionLabel.Text = 'Use Frictional decay reduction';
            app.switch_usefric = uiswitch(app.panel_setupsens, 'slider');
            app.MinimumlayerthicknessEditFieldLabel = uilabel(app.panel_setupsens);
            app.MinimumlayerthicknessEditFieldLabel.Text = 'Minimum layer thickness:';
            app.edit_minthickness = uieditfield(app.panel_setupsens, 'numeric');
            app.HorizontalthermalconductivityanisotropyLabel = uilabel(app.panel_setupsens);
            app.HorizontalthermalconductivityanisotropyLabel.Text = 'Horizontal thermal conductivity anisotropy:';
            app.edit_kanisotropy = uieditfield(app.panel_setupsens, 'numeric');
            app.label_NOTusingHP_2 = uilabel(app.panel_setupsens);
            app.label_NOTusingHP_2.Text = 'Variable layer thickness values only:';
            app.label_NOTusingHP_3 = uilabel(app.panel_setupsens);
            app.label_NOTusingHP_3.Text = 'Variable thermal conductivity values and layer thickness values:';
            app.button_runsensanalysis = uibutton(app.panel_setupsens, 'push');
            app.button_runsensanalysis.ButtonPushedFcn = createCallbackFcn(app, @button_runsensanalysisPushed, true);
            app.button_runsensanalysis.Text = 'Run Sensitivity Analysis';
            % Show the figure after all components are created
            app.figure_SensAnalSetUp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetUpSensAnalysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure_SensAnalSetUp)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure_SensAnalSetUp)
        end
    end
end